classdef Camima < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CamimaMain                    matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        PushTool2                     matlab.ui.container.toolbar.PushTool
        ToggleTool                    matlab.ui.container.toolbar.ToggleTool
        PushTool3                     matlab.ui.container.toolbar.PushTool
        GridLayout                    matlab.ui.container.GridLayout
        MainTabGroup                  matlab.ui.container.TabGroup
        HOMETab                       matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        CameraPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        CameraBrowserButton           matlab.ui.control.Button
        CameraSettingButton           matlab.ui.control.Button
        ImastPanel                    matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        NewVuimaButton                matlab.ui.control.Button
        ControlPanel                  matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        StartAllButton                matlab.ui.control.Button
        StopAllButton                 matlab.ui.control.Button
        TestClick                     matlab.ui.control.Button
        AutoClick                     matlab.ui.control.CheckBox
        AutoClickX                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        AutoClickY                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        MoreTab                       matlab.ui.container.Tab
        PCOsingleimageEditFieldLabel  matlab.ui.control.Label
        PCOsingleimage                matlab.ui.control.NumericEditField
        Status                        matlab.ui.control.EditField
    end


    properties (Access = public)
        cameraTree % camera info and handles of all cameras
        vid %all selected camera ports
        subuiTree %handles of all sub-UI
        img % image
        imast % all available imast: image stream
        selectedimast % all running imast
        robot % Description
    end

    methods (Access = public)
    
        function ResetAllCameras(app)
            app.Status.Value = 'Reseting all cameras...';
            imaqreset; %reset all cameras from image acquisition tool box
            %clear app.cameraTree
            app.cameraTree = [];
            %if success status bar will get feedback
            app.Status.Value = 'All cameras has been reset!';           
        end


        function cameraBrowser(app,position)
            if isfield(app.subuiTree,'cameraBrowser') && app.subuiTree.cameraBrowser.Count == 1 && ishandle(app.subuiTree.cameraBrowser.obj)
                browser = app.subuiTree.cameraBrowser.obj;
                browser.Position = [position(1) position(2)-600 250 500];
                browser.Visible = 'on';
            else
                %new GUI for camera browser
                browser = figure('MenuBar','none','Name','Camera Browser','NumberTitle','off',...
                    'ToolBar','none','Position',[position(1) position(2)-600 250 500],'CloseRequestFcn',@app.cameraBrowserClose);
                
                %add toolbar for push button
                toolbar = uitoolbar(browser);
                
                % save main handle app in UserData
                browser.UserData = app;
                
                % add series button for: reset, refresh and setting cameras
                
                % Load the Redo icon
  
                uipushtool('Parent',toolbar,'TooltipString','Reset all cameras','ClickedCallback',...
                    @app.resetAndRefreshCameraBrowser);
                uipushtool('Parent',toolbar,'TooltipString','Refresh all cameras','ClickedCallback',...
                    @app.refreshCameraBrowser);
                uipushtool('Parent',toolbar,'TooltipString','Get and Initialize chosen cameras','ClickedCallback',...
                    @app.GetAndInitializeChosenCamera);
                uipushtool('Parent',toolbar,'TooltipString','Set chosen cameras','ClickedCallback',...
                    @app.SetChosenCamera);
                uipushtool('Parent',toolbar,'TooltipString','Preview','ClickedCallback',...
                    @app.PreviewCamera);
                uipushtool('Parent',toolbar,'TooltipString','Start','ClickedCallback',...
                    @app.StartCamera);
                uipushtool('Parent',toolbar,'TooltipString','Stop','ClickedCallback',...
                    @app.StopCamera);
                
                %refresh cameraTree in browser
                refreshCameraBrowser(app,browser)
                app.subuiTree.cameraBrowser.Count = 1;
                
                %count set to 1
                app.subuiTree.cameraBrowser.obj = browser;
                app.subuiTree.cameraBrowser.Count = 1;
            end
        end


        function StartCamera(app,~,~)
            %get tree-node package
            import uiextras.jTree.*;
                        
            %get tree handle
            f = gcf;
            panel = f.Children(1);
            t = panel.UserData;
            selectedNodes = t.SelectedNodes;
            selectedNodesNumber = length(selectedNodes);
            
            for i = 1:selectedNodesNumber
                node = selectedNodes(i);
                %format = node.Name;
                cameraID = node.Parent.Value;
                adaptor = node.Parent.Parent.Name;
                %get camera handles, put into vid
                selectedvid = app.vid.([adaptor '_' num2str(cameraID)]);
                %selectedsrc = getselectedsource(selectedvid);
                              
                if strcmp(selectedvid.Running,'off')
                    UD = selectedvid.UserData;
                    UD.ContinueQ = 1;
                    selectedvid.UserData = UD;
                    start(selectedvid);
                    
                else
                    app.Status.Value = 'Already start, waiting for triggers!';
                end
                                
            end
        end


        function SetChosenCamera(app,~,~)
            %get tree-node package
            import uiextras.jTree.*;
                        
            %get tree handle
            f = gcf;
            panel = f.Children(1);
            t = panel.UserData;
            selectedNodes = t.SelectedNodes;
            selectedNodesNumber = length(selectedNodes);
            
            for i = 1:selectedNodesNumber
                node = selectedNodes(i);
                %format = node.Name;
                cameraID = node.Parent.Value;
                adaptor = node.Parent.Parent.Name;
                %get camera handles, put into vid
                selectedvid = app.vid.([adaptor '_' num2str(cameraID)]);
                selectedsrc = getselectedsource(selectedvid);
                open selectedvid; open selectedsrc;
            end
        end


        function GetAndInitializeChosenCamera(app,~,~)
            %get tree-node package
            import uiextras.jTree.*;
                        
            %get tree handle
            f = gcf;
            panel = f.Children(1);
            t = panel.UserData;
            selectedNodes = t.SelectedNodes;
            selectedNodesNumber = length(selectedNodes);
            
            %get all camera wanna activate
            cameraAllWanna = [];
            
            for i = 1:selectedNodesNumber
                node = selectedNodes(i);
                
                cameraID = node.Parent.Value;
                adaptor = node.Parent.Parent.Name;
                cameraAllWanna = [cameraAllWanna,{[adaptor '_' num2str(cameraID)]}];
            end
            
            %judge is there any duplicate
            if length(cameraAllWanna) ~= length(unique(cameraAllWanna))
                camima = f.UserData;
                camima.Status.Value = 'Please choose only one format under each camera!';
            else
                
                for i = 1:selectedNodesNumber
                    node = selectedNodes(i);
                    format = node.Name;
                    cameraID = node.Parent.Value;
                    adaptor = node.Parent.Parent.Name;
                    %get camera handles, put into vid
                    cameraname = [adaptor '_' num2str(cameraID)];
                    app.vid.(cameraname) = videoinput(adaptor,cameraID,format);
                    %Initialize setting
                    switch adaptor
                        case {'pcocameraadaptor','pcocameraadaptor_r2020b'}
                            InitializePCO(app,app.vid.([adaptor '_' num2str(cameraID)]));
                            % set image stream to app.img.imast
                            % for PCO we always use double image, thus save two imast as A and B
                            if ~isempty(app.imast)
                                app.imast = [app.imast,{[adaptor '_' num2str(cameraID) '_A']}];
                                app.imast = [app.imast,{[adaptor '_' num2str(cameraID) '_B']}];
                            else
                                app.imast = {[adaptor '_' num2str(cameraID) '_A'],[adaptor '_' num2str(cameraID) '_B']};
                            end
                        case {'pointgrey','mwpointgreyimaq'}
                            InitializePG(app,app.vid.([adaptor '_' num2str(cameraID)]));
                            % set image stream to app.img.imast
                            if ~isempty(app.imast)
                                app.imast = [app.imast,{[adaptor '_' num2str(cameraID)]}];
                            else
                                app.imast = {[adaptor '_' num2str(cameraID)]};
                            end
                        case 'gige'
                            % for now the gige adaptor is for the Blackfly
                            % camera which actually with an user defined
                            % setting memor.
                            InitializeCameraWithMem(app,app.vid.([adaptor '_' num2str(cameraID)]));
                            if ~isempty(app.imast)
                                app.imast = [app.imast,{[adaptor '_' num2str(cameraID)]}];
                            else
                                app.imast = {[adaptor '_' num2str(cameraID)]};
                            end
                    end
                end
                
                camima = f.UserData;
                camima.CameraSettingButton.Enable = 'on';
                camima.NewVuimaButton.Enable = 'on';
                camima.StartAllButton.Enable = 'on';
            end
        end


        function SearchAndGetCameras(app)
            %search for cameras
            inputs = imaqhwinfo;
            %List Adaptors Available
            adaptorsLogged = inputs.InstalledAdaptors;
            %count Adaptors number
            adaptorLoggedNumber = length(adaptorsLogged);
            %save adaptors in app.cameraTree
            app.cameraTree.adaptor.Name = adaptorsLogged;
            app.cameraTree.adaptor.Number = adaptorLoggedNumber;
            app.cameraTree.basicinfo.adaptorNumber = adaptorLoggedNumber;
            
            %initialize cameraNumber for Basicinfo
            app.cameraTree.basicinfo.cameraNumber = 0;
            
            if adaptorLoggedNumber ~= 0
                %Test all available cameras
                for i = 1:adaptorLoggedNumber
                    %get info for all cameras
                    adaptorName = adaptorsLogged{i};
                    adaptorinfo = imaqhwinfo(adaptorName);
                    cameraIDs = adaptorinfo.DeviceIDs;
                    cameraNumber = length(cameraIDs);
                    %renew total cameraNumber in basicinfo
                    app.cameraTree.basicinfo.cameraNumber = app.cameraTree.basicinfo.cameraNumber +cameraNumber;
                    
                    %record available camera for this adaptor
                    app.cameraTree.(adaptorName).ID = cameraIDs;
                    app.cameraTree.(adaptorName).Number = cameraNumber;
                    
                    if cameraNumber ~= 0
                        % Test all Format for camera
                        for j = 1:cameraNumber
                            camerainfo = imaqhwinfo(adaptorName,cameraIDs{j});
                            app.cameraTree.([adaptorName '_' num2str(cameraIDs{j})]) = camerainfo;
                        end
                    end
                end
                
                %tell users status of all camera
                app.Status.Value = ['Search End: ' num2str(app.cameraTree.adaptor.Number) ' adaptors available;  ' ...
                    num2str(app.cameraTree.basicinfo.cameraNumber) ' cameras available.'];
            else
                app.Status.Value = 'No Adaptors available, please install them first!';
            end
        end


        function refreshCameraBrowser(app,~,~)
            %get tree-node package
            import uiextras.jTree.*;
                        
            %for sub-figure callback
            f = gcf;
            t = Tree('Parent',f);
            t.SelectionType = 'discontiguous';
            
            adaptorName = app.cameraTree.adaptor.Name;
            adaptorNumber = app.cameraTree.adaptor.Number;
            for i = 1:adaptorNumber
                % build adaptor node
                adaptornode = TreeNode('Name',adaptorName{i},'Parent',t.Root);
                
                %get cameras for this adaptor
                cameraID =  app.cameraTree.(adaptorName{i}).ID;
                cameraNumber = app.cameraTree.(adaptorName{i}).Number;
                for j = 1:cameraNumber
                    %construct cameratree
                    cameranode = TreeNode('Name',[adaptorName{i} '_' num2str(cameraID{j})],'Value',cameraID{j},'Parent',adaptornode);
                    %set selection type to be single
                    %cameranode.Tree.SelectionType = 'Single';
                    
                    %get all format for this camera
                    camerainfo = app.cameraTree.([adaptorName{i} '_' num2str(cameraID{j})]);
                    supportedformat = camerainfo.SupportedFormats;
                    supportedformatNumber = length(supportedformat);
                    for k = 1:supportedformatNumber
                        TreeNode('Name',supportedformat{k},'Parent',cameranode);
                    end
                    %expand cameranode
                    cameranode.expand();
                end
                %expand adaptors
                adaptornode.expand();
            end
            %expand all
            t.Root.expand();
        end


        function resetAndRefreshCameraBrowser(app,~,~)
            ResetAllCameras(app);
            SearchAndGetCameras(app);
            refreshCameraBrowser(app);
        end


        function PGCallBack(app,obj,event)
            
            errID = 'imaq:imaqcallback:invalidSyntax';
            errID2 = 'imaq:imaqcallback:zeroInputs';
            
            switch nargin
                case 0
                    error(message(errID2));
                case 1
                    error(message(errID));
                case 2
                    if ~isa(obj, 'imaqdevice') || ~isa(event, 'struct')
                        error(message(errID));
                    end
                    if ~(isfield(event, 'Type') && isfield(event, 'Data'))
                        error(message(errID));
                    end
            end
            
            % Determine the type of event.
            EventType = event.Type;
            
            % Determine the time of the error event.
            EventData = event.Data;
            EventDataTime = EventData.AbsTime;
            
            % Create a display indicating the type of event, the time of the event and
            % the name of the object.
            name = get(obj, 'Name');
            nameSP = split(name,'-');
            cameraName = [nameSP{2} '_' nameSP{3}];
            % show callback info on app.Status
            switch EventType
                case 'Start'
                    app.Status.Value = [name ' start at ' datestr(EventDataTime,13)...
                        ' , waiting for trigger 1/' num2str(obj.FramesAcquiredFcnCount)];
                case 'Trigger'
                    app.Status.Value = [name ' is triggered at ' datestr(EventDataTime,13)...
                        ' , waiting for trigger ' num2str(obj.FramesAvailable+1) '/' num2str(obj.FramesAcquiredFcnCount)];
                case 'Stop'
                    app.Status.Value = [EventType ' event occurred at ' datestr(EventDataTime,13) ' for video input object: ' name];
                case 'FramesAcquired'
                    app.Status.Value = [EventType ' event occurred at ' datestr(EventDataTime,13) ' for video input object: ' name];
                    
                    %get data from vid
                    for i = 1:obj.FramesAvailable
                        tempData = getdata(obj);
                        app.img.(cameraName).(['IMG' num2str(i)]) = tempData/4;
                    end
                    
                    %modify? Light picture
                    if isfield(app.selectedimast,([(cameraName)]))
                        vuima = app.selectedimast.([(cameraName)]);
                        if(vuima.LModify.Value == 1)
                            x1=vuima.Lx.Value;
                            x2=x1+vuima.W.Value;
                            y1=vuima.Uy.Value;
                            y2=y1+vuima.H.Value;
                            IMGtemp_1 = double(app.img.([(cameraName)]).IMG1);
                            IMGtemp_2 = double(app.img.([(cameraName)]).IMG2);
                            Mean1 = mean(mean(IMGtemp_1(y1:y2,x1:x2)));
                            Mean2 = mean(mean(IMGtemp_2(y1:y2,x1:x2)));
                            app.img.([(cameraName)]).IMG2 = uint16(double(app.img.([(cameraName)]).IMG2) * (Mean1 /Mean2));
                        end
                    end
                    
                    %Method from OLD VB program to get OD


                    %get OD
                    %                     app.img.(cameraName).IMGOD =...
                    %                         CutLog(app,double(app.img.(cameraName).IMG2) - double(app.img.(cameraName).IMG3))-...
                    %                         CutLog(app,double(app.img.(cameraName).IMG1) - double(app.img.(cameraName).IMG3));
                    
                    %get OD
                    
                    if isfield(app.selectedimast,(cameraName))
                        
                        Csat = app.selectedimast.(cameraName).Csat.Value;
                        Bin = app.selectedimast.(cameraName).Bin.Value;
                        Expo = app.selectedimast.(cameraName).Expo.Value;
                        %if Condi is 1, add modification, if Condi is 0, no modification
                        Condi = app.selectedimast.(cameraName).Condi.Value;
                        app.img.(cameraName).IMGOD =...
                            200 + 1000*(CutLog(app,(double(app.img.(cameraName).IMG2) - double(app.img.(cameraName).IMG3))./...
                            (double(app.img.(cameraName).IMG1) - double(app.img.(cameraName).IMG3))) + ...
                            Condi * (double(app.img.(cameraName).IMG2) - double(app.img.(cameraName).IMG1))/(Expo*(Bin^2)*Csat));
                        
                    end

                    
                    %show OD  and  do fitting and show fitting result
                    if isfield(app.selectedimast,(cameraName))
                        %get vuima
                        vuima = app.selectedimast.(cameraName);
                        %transfer data
                        vuima.curimage = app.img.(cameraName).IMGOD;
                        vuima.otherimage.IMG1 = app.img.(cameraName).IMG1;
                        vuima.otherimage.IMG2 = app.img.(cameraName).IMG2;
                        vuima.otherimage.IMG3 = app.img.(cameraName).IMG3;
                        
                        %write binning info to vuima
                        %get videoresolution
                        Reso = obj.VideoResolution;
                        valueW = Reso(1);
                        valueH = Reso(2);
                        vuima.curBin.x = 1384/valueW;
                        vuima.curBin.y = 1032/valueH;
                        
                        %show mean of light picture 
                        MeanLight = mean(mean(vuima.otherimage.IMG2)) - mean(mean(vuima.otherimage.IMG3));
                        vuima.Light.Value = MeanLight;
                        
                        %and show counting rate
                        Expo = app.selectedimast.(cameraName).Expo.Value;
                        Bin = app.selectedimast.(cameraName).Bin.Value;
                        vuima.CountRate.Value = MeanLight / (Expo * Bin^2);
                        
                        %set show aspect ratio
                        imgsize = size(app.img.(cameraName).IMGOD);
                        vuima.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                        %imshow(app.img.(cameraName).IMGOD,'Parent',vuima.ImageShow,'XData',[1 imgsize(2)],'YData',[1 imgsize(1)]);
                        imagesc(vuima.ImageShow,app.img.(cameraName).IMGOD);
                        %chnge colormap to jet
                        colormap(vuima.ImageShow,jet);
                        colorbar(vuima.ImageShow,'southoutside');
                    
                        %saving
                        if vuima.AutoSave.Value
                            vuima.saveimage();
                        end
                        
                        %fitting
                        if vuima.Auto.Value
                            vuima.fit();
                        end

                    end
                    
                case 'Timer'
                    app.Status.Value = 'Wooo... its really a long experiment! Be patient, Dude.';
            end
            % Display the error string.
            if strcmpi(EventType, 'error')
                app.Status.Value = EventData.Message;
            end
            
        end


        function PCOCallBack(app,obj,event)
            
            errID = 'imaq:imaqcallback:invalidSyntax';
            errID2 = 'imaq:imaqcallback:zeroInputs';
            
            switch nargin
                case 0
                    error(message(errID2));
                case 1
                    error(message(errID));
                case 2
                    if ~isa(obj, 'imaqdevice') || ~isa(event, 'struct')
                        error(message(errID));
                    end
                    if ~(isfield(event, 'Type') && isfield(event, 'Data'))
                        error(message(errID));
                    end
            end
            
            % Determine the type of event.
            EventType = event.Type;
            
            % Determine the time of the error event.
            EventData = event.Data;
            EventDataTime = EventData.AbsTime;
            
            % Create a display indicating the type of event, the time of the event and
            % the name of the object.
            name = get(obj, 'Name');
            nameSP = split(name,'-');
            cameraName = [nameSP{2} '_' nameSP{3}];
            % show callback info on app.Status
            switch EventType
                case 'Start'
                    app.Status.Value = [name ' start at ' datestr(EventDataTime,13)...
                        ' , waiting for trigger 1/' num2str(obj.FramesAcquiredFcnCount)];
                case 'Trigger'
                    app.Status.Value = [name ' is triggered at ' datestr(EventDataTime,13)...
                        ' , waiting for trigger ' num2str(obj.FramesAvailable+1) '/' num2str(obj.FramesAcquiredFcnCount)];
                case 'Stop'
                    app.Status.Value = [EventType ' event occurred at ' datestr(EventDataTime,13) ' for video input object: ' name];
                case 'FramesAcquired'
                    app.Status.Value = [EventType ' event occurred at ' datestr(EventDataTime,13) ' for video input object: ' name];
                    
                    %get original size
                    OriSize = obj.ROIposition;
                    
                    %get ROI for PCO
                    UD = obj.UserData;
                    ROI = UD.ROIposition;
                    
                    %get data from vid
                    for i = 1:obj.FramesAvailable
                        totalImg = getdata(obj);
                        % substract 10*********** as 2^15 = 32768
                        % totalImg = totalImg;
                        
                        app.img.([cameraName '_A']).(['IMG' num2str(i)]) = totalImg((1+ROI(2)):(ROI(2)+ROI(4)),(ROI(1)+1):(ROI(1)+ROI(3)));
                        app.img.([cameraName '_B']).(['IMG' num2str(i)]) = totalImg((OriSize(4)/2+ROI(2)+1):(OriSize(4)/2+ROI(2)+ROI(4)),(1+ROI(1)):(ROI(1)+ROI(3)));
                    end
                    
                    %                     Method from OLD VB program
                    %                     '1000 * Log(Abs((light(i, j) - dark(i, j)) / (shadow(i, j) - dark(i, j))))
                    %                     If (shadow(i, j) <> darkS(i, j)) AndAlso Math.Sign(light(i, j) - darkL(i, j)) = Math.Sign(shadow(i, j) - darkS(i, j)) Then
                    %                     ddata(i, j) = CLng(1000 * Math.Log((light(i, j) - darkL(i, j)) / (shadow(i, j) - darkS(i, j))))
                    %                     If ddata(i, j) <= CLng(-200) Then
                    %                     ddata(i, j) = CLng(200)
                    %                     Else : ddata(i, j) = CLng(ddata(i, j) + 200)
                    %                     End If
                    %                     Else
                    %                     ddata(i, j) = 200
                    %                     End If
                    if app.PCOsingleimage.Value == 1
                        %get OD
                        if isfield(app.selectedimast,([cameraName '_A']))
                            
                            app.img.([cameraName '_A']).IMGOD =...
                                200 + 1000*(CutLog(app,(double(app.img.([cameraName '_B']).IMG1) - double(app.img.([cameraName '_B']).IMG3))./...
                                (double(app.img.([cameraName '_A']).IMG1) - double(app.img.([cameraName '_A']).IMG3))));
                            
                        end
                        
                        %get binning parameter
                        PCOsrc = getselectedsource(obj);
                        
                        %show OD  and  do fitting and show fitting result
                        if isfield(app.selectedimast,([cameraName '_A']))
                            %get vuima
                            vuima = app.selectedimast.([cameraName '_A']);
                            %transfer data
                            vuima.curimage = app.img.([cameraName '_A']).IMGOD;
                            vuima.otherimage.IMG1 = app.img.([cameraName '_A']).IMG1;
                            vuima.otherimage.IMG2 = app.img.([cameraName '_B']).IMG1;
                            vuima.otherimage.IMG3 = app.img.([cameraName '_A']).IMG3;
                            %write binning info to vuima
                            vuima.curBin.x = str2double(PCOsrc.B1BinningHorizontal);
                            vuima.curBin.y = str2double(PCOsrc.B2BinningVertical);
                            
                            %show mean of light picture
                            MeanLight = mean(mean(vuima.otherimage.IMG2)) - mean(mean(vuima.otherimage.IMG3));
                            vuima.Light.Value = MeanLight;
                            
                            
                            %set show aspect ratio
                            imgsize = size(app.img.([cameraName '_A']).IMGOD);
                            vuima.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                            %imshow(app.img.(cameraName).IMGOD,'Parent',vuima.ImageShow,'XData',[1 imgsize(2)],'YData',[1 imgsize(1)]);
                            imagesc(vuima.ImageShow,app.img.([cameraName '_A']).IMGOD);
                            %chnge colormap to jet
                            colormap(vuima.ImageShow,jet);
                            colorbar(vuima.ImageShow,'southoutside');
                            
                            %pixelinfo
                            %impixelinfo(vuima.ImageShow);
                            
                        end
                        
                        if isfield(app.selectedimast,([cameraName '_A']))
                            %get vuima
                            vuima = app.selectedimast.([cameraName '_A']);
                            
                            %saving
                            if vuima.AutoSave.Value
                                vuima.saveimage();
                            end
                            
                            %fitting
                            if vuima.Auto.Value
                                vuima.fit();
                            end
                            
                        end
                    else
                        %modify? Light picture
                        if isfield(app.selectedimast,([cameraName '_A']))
                            vuima = app.selectedimast.([cameraName '_A']);
                            if(vuima.LModify.Value == 1)
                                x1=vuima.Lx.Value;
                                x2=x1+vuima.W.Value;
                                y1=vuima.Uy.Value;
                                y2=y1+vuima.H.Value;
                                IMGtemp_1 = double(app.img.([cameraName '_A']).IMG1);
                                IMGtemp_2 = double(app.img.([cameraName '_A']).IMG2);
                                Mean1 = mean(mean(IMGtemp_1(y1:y2,x1:x2)));
                                Mean2 = mean(mean(IMGtemp_2(y1:y2,x1:x2)));
                                app.img.([cameraName '_A']).IMG2 = uint16(double(app.img.([cameraName '_A']).IMG2) * (Mean1 /Mean2));
                            end
                        end
                        
                        if isfield(app.selectedimast,([cameraName '_B']))
                            vuima = app.selectedimast.([cameraName '_B']);
                            if(vuima.LModify.Value == 1)
                                x1=vuima.Lx.Value;
                                x2=x1+vuima.W.Value;
                                y1=vuima.Uy.Value;
                                y2=y1+vuima.H.Value;
                                IMGtemp_1 = double(app.img.([cameraName '_B']).IMG1);
                                IMGtemp_2 = double(app.img.([cameraName '_B']).IMG2);
                                Mean1 = mean(mean(IMGtemp_1(y1:y2,x1:x2)));
                                Mean2 = mean(mean(IMGtemp_2(y1:y2,x1:x2)));
                                app.img.([cameraName '_B']).IMG2 = uint16(double(app.img.([cameraName '_B']).IMG2) * (Mean1 /Mean2));
                            end
                        end
                        
                        %get OD _A
                        
                        if isfield(app.selectedimast,([cameraName '_A']))
                            
                            CsatA = app.selectedimast.([cameraName '_A']).Csat.Value;
                            BinA = app.selectedimast.([cameraName '_A']).Bin.Value;
                            ExpoA = app.selectedimast.([cameraName '_A']).Expo.Value;
                            %if Condi is 1, add modification, if Condi is 0, no modification
                            CondiA = app.selectedimast.([cameraName '_A']).Condi.Value;
                            app.img.([cameraName '_A']).IMGOD =...
                                200 + 1000*(CutLog(app,(double(app.img.([cameraName '_A']).IMG2) - double(app.img.([cameraName '_A']).IMG3))./...
                                (double(app.img.([cameraName '_A']).IMG1) - double(app.img.([cameraName '_A']).IMG3))) + ...
                                CondiA * (double(app.img.([cameraName '_A']).IMG2) - double(app.img.([cameraName '_A']).IMG1))/(ExpoA*(BinA^2)*CsatA));
                            
                        end
                        %get OD _B
                        
                        if isfield(app.selectedimast,([cameraName '_B']))
                            CsatB = app.selectedimast.([cameraName '_B']).Csat.Value;
                            BinB = app.selectedimast.([cameraName '_B']).Bin.Value;
                            ExpoB = app.selectedimast.([cameraName '_B']).Expo.Value;
                            %if Condi is 1, add modification, if Condi is 0, no modification
                            CondiB = app.selectedimast.([cameraName '_B']).Condi.Value;
                            app.img.([cameraName '_B']).IMGOD =...
                                200 + 1000*(CutLog(app,(double(app.img.([cameraName '_B']).IMG2) - double(app.img.([cameraName '_B']).IMG3))./...
                                (double(app.img.([cameraName '_B']).IMG1) - double(app.img.([cameraName '_B']).IMG3))) + ...
                                CondiB * (double(app.img.([cameraName '_B']).IMG2) - double(app.img.([cameraName '_B']).IMG1))/(ExpoB*(BinB^2)*CsatB));
                        end
                        
                        %get binning parameter
                        PCOsrc = getselectedsource(obj);
                        
                        %test parallel computing
                        %parfor ii=1:2
                        %    if ii == 1
                        %        display(ii)
                        %    else
                        %        display(ii)
                        %    end
                        %end
                        
                        
                        %show OD  and  do fitting and show fitting result
                        if isfield(app.selectedimast,([cameraName '_A']))
                            %get vuima
                            vuima = app.selectedimast.([cameraName '_A']);
                            %transfer data
                            vuima.curimage = app.img.([cameraName '_A']).IMGOD;
                            vuima.otherimage.IMG1 = app.img.([cameraName '_A']).IMG1;
                            vuima.otherimage.IMG2 = app.img.([cameraName '_A']).IMG2;
                            vuima.otherimage.IMG3 = app.img.([cameraName '_A']).IMG3;
                            %write binning info to vuima
                            vuima.curBin.x = str2double(PCOsrc.B1BinningHorizontal);
                            vuima.curBin.y = str2double(PCOsrc.B2BinningVertical);
                            
                            %show mean of light picture
                            MeanLight = mean(mean(vuima.otherimage.IMG2)) - mean(mean(vuima.otherimage.IMG3));
                            vuima.Light.Value = MeanLight;
                            
                            %show count rate
                            ExpoA = app.selectedimast.([cameraName '_A']).Expo.Value;
                            BinA = app.selectedimast.([cameraName '_A']).Bin.Value;
                            vuima.CountRate.Value = MeanLight / (ExpoA * BinA^2);
                            
                            %set show aspect ratio
                            imgsize = size(app.img.([cameraName '_A']).IMGOD);
                            vuima.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                            %imshow(app.img.(cameraName).IMGOD,'Parent',vuima.ImageShow,'XData',[1 imgsize(2)],'YData',[1 imgsize(1)]);
                            imagesc(vuima.ImageShow,app.img.([cameraName '_A']).IMGOD);
                            %change colormap to jet
                            colormap(vuima.ImageShow,jet);
                            colorbar(vuima.ImageShow,'southoutside');
                            
                            %pixelinfo
                            %impixelinfo(vuima.ImageShow);
                            
                        end
                        
                        if isfield(app.selectedimast,([cameraName '_B']))
                            %get vuima
                            vuima = app.selectedimast.([cameraName '_B']);
                            %transfer data
                            vuima.curimage = app.img.([cameraName '_B']).IMGOD;
                            vuima.otherimage.IMG1 = app.img.([cameraName '_B']).IMG1;
                            vuima.otherimage.IMG2 = app.img.([cameraName '_B']).IMG2;
                            vuima.otherimage.IMG3 = app.img.([cameraName '_B']).IMG3;
                            %write binning info to vuima
                            vuima.curBin.x = str2double(PCOsrc.B1BinningHorizontal);
                            vuima.curBin.y = str2double(PCOsrc.B2BinningVertical);
                            
                            %show mean of light picture
                            MeanLight = mean(mean(vuima.otherimage.IMG2)) - mean(mean(vuima.otherimage.IMG3));
                            vuima.Light.Value = MeanLight;
                            
                            %show count rate
                            ExpoB = app.selectedimast.([cameraName '_B']).Expo.Value;
                            BinB = app.selectedimast.([cameraName '_B']).Bin.Value;
                            vuima.CountRate.Value = MeanLight / (ExpoB * BinB^2);
                            
                            %set show aspect ratio
                            imgsize = size(app.img.([cameraName '_B']).IMGOD);
                            vuima.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                            %imshow(app.img.(cameraName).IMGOD,'Parent',vuima.ImageShow,'XData',[1 imgsize(2)],'YData',[1 imgsize(1)]);
                            imagesc(vuima.ImageShow,app.img.([cameraName '_B']).IMGOD);
                            %chnge colormap to jet
                            colormap(vuima.ImageShow,jet);
                            colorbar(vuima.ImageShow,'southoutside');
                            
                            %pixelinfo
                            %impixelinfo(vuima.ImageShow);
                            
                        end
                        
                        if isfield(app.selectedimast,([cameraName '_A']))
                            %get vuima
                            vuima = app.selectedimast.([cameraName '_A']);
                            
                            %saving
                            if vuima.AutoSave.Value
                                vuima.saveimage();
                            end
                            
                            %fitting
                            if vuima.Auto.Value
                                vuima.fit();
                            end
                            
                        end
                        
                        if isfield(app.selectedimast,([cameraName '_B']))
                            %get vuima
                            vuima = app.selectedimast.([cameraName '_B']);
                            
                            %fitting
                            if vuima.Auto.Value
                                vuima.fit();
                            end
                            
                            %saving
                            if vuima.AutoSave.Value
                                vuima.saveimage();
                            end
                            
                        end
                    end
                    
                    %everything done
                    %SoundData = load('chirp.mat');
                    %SoundObj = audioplayer(SoundData.y,SoundData.Fs);
                    %play(SoundObj);
                    %pause(2);
                    
                    %auto click after each shot(for auto re-new conditiion)
                    if app.AutoClick.Value == 1
                        XautoClick = app.AutoClickX.Value;
                        YautoClick = app.AutoClickY.Value;
                        app.robot.mouseMove(XautoClick,YautoClick);
                        %app.robot.mousePress(java.awt.event.InputEvent.BUTTON1_MASK); %This press does not work 
                        %app.robot.mouseRelease (java.awt.event.InputEvent.BUTTON1_MASK);
                        java.awt.event.InputEvent.BUTTON1_MASK;
                    end
                    
                case 'Timer'
                    app.Status.Value = 'Wooo... its really a long experiment! Be patient, Dude.';
            end
            % Display the error string.
            if strcmpi(EventType, 'error')
                app.Status.Value = EventData.Message;
            end
        end


        function results = InitializePG(app,PGvid)
            app.Status.Value = ['Initializing ' PGvid.Name '...'];
            
            PGsrc = getselectedsource(PGvid);
            
            %Turn on callback FCN
            PGvid.StartFcn = @app.PGCallBack;
            PGvid.TriggerFcn = @app.PGCallBack;
            PGvid.StopFcn = @app.PGCallBack;
            PGvid.TimerFcn = @app.PGCallBack;
            PGvid.FramesAcquiredFcn = @app.PGCallBack;
            
            %Initialize setting
            
            
            %General
            PGsrc.Brightness = 0;
            PGsrc.ExposureMode = 'Off';
            %PGsrc.Exposure = 0;
            PGsrc.FrameRateMode = 'Off';
            %PGsrc.FrameRate = 30;
            %PGsrc.FrameRatePercentageMode = 'Off';
            %PGsrc.FrameRatePercentage = 100;
            PGsrc.GainMode = 'Manual';
            PGsrc.Gain = 0;
            PGsrc.GammaMode = 'Off';
            %PGsrc.Gamma = 1;
            %PGsrc.SharpnessMode = 'Off';
            %PGsrc.Sharpness = 0;
            PGsrc.ShutterMode = 'Manual';
            PGsrc.Shutter = 0.125;
            PGsrc.TriggerDelayMode = 'Off';
            
            %Setting Strobe and trigger delay
            %%%%%%%%%%%%%%   need  complete
            
            %Setting
            PGvid.FramesPerTrigger = 1;
            PGvid.ReturnedColorSpace = 'grayscale';
            %Trigger
            triggerconfig(PGvid,'hardware','risingEdge','externalTriggerMode0-Source0');
            PGvid.TriggerRepeat = inf;
            PGvid.FramesPerTrigger = 1;
            %every 3 picture do process
            PGvid.FramesAcquiredFcnCount = 3;
            
            %ROI
            %get videoresolution
            Reso = PGvid.VideoResolution;
            valueW = Reso(1);
            valueH = Reso(2);
            PGvid.ROIposition = [0 0 valueW valueH];
            
            %Disk logging
            PGvid.LoggingMode = 'memory';
            %Using UserData to record filefolder and filename and others
            today = split(date,'-');
            UD = struct('SelectFolder',['D:\data\' today{3} '\' today{2} '\' today{1}],...
                'FileName','RbUp',...
                'Autoadd',1,...
                'ContinueQ',1);
            PGvid.UserData = UD;
            
            %others
            PGvid.TimerPeriod = 80; %one shot with more than 80 seconds is really long
            
            app.Status.Value = [PGvid.Name ' has been initialized!'];
            results = 1;
            
        end
        
        function results = InitializeCameraWithMem(app,vid)
            app.Status.Value = ['Initializing ' vid.Name '...'];
            
            src = getselectedsource(vid);
            
            %Turn on callback FCN
            vid.StartFcn = @app.BFCallBack;
            vid.TriggerFcn = @app.BFCallBack;
            vid.StopFcn = @app.BFCallBack;
            vid.TimerFcn = @app.BFCallBack;
            vid.FramesAcquiredFcn = @app.BFCallBack;
            
            %Initialize setting
            %
            %  NEED ADD initial setting for this camera
            %
            %%%%%%%%%%%%%%%%%%%%%%
            
            
            %Setting
            vid.FramesPerTrigger = 1;
            vid.ReturnedColorSpace = 'grayscale';
            %Trigger
            triggerconfig(vid,'hardware');
            vid.TriggerRepeat = inf;
            vid.FramesPerTrigger = 1;
            %every 3 picture do process
            vid.FramesAcquiredFcnCount = 3;
            
            %ROI
            %get videoresolution
            Reso = vid.VideoResolution;
            valueW = Reso(1);
            valueH = Reso(2);
            vid.ROIposition = [0 0 valueW valueH];
            
            %Disk logging
            vid.LoggingMode = 'memory';
            %Using UserData to record filefolder and filename and others
            today = split(date,'-');
            UD = struct('SelectFolder',['D:\data\' today{3} '\' today{2} '\' today{1}],...
                'FileName','RbUp',...
                'Autoadd',1,...
                'ContinueQ',1);
            vid.UserData = UD;
            
            %others
            vid.TimerPeriod = 80; %one shot with more than 80 seconds is really long
            
            app.Status.Value = [vid.Name ' has been initialized!'];
            results = 1;
            
        end        


        function results = InitializePCO(app,PCOvid)
            app.Status.Value = ['Initializing pcocameraadaptor_' PCOvid.Name '...'];

                %get source
                PCOsrc = getselectedsource(PCOvid);
                
                %Turn on callback FCN
                PCOvid.StartFcn = @app.PCOCallBack;
                PCOvid.TriggerFcn = @app.PCOCallBack;
                PCOvid.StopFcn = @app.PCOCallBack;
                PCOvid.TimerFcn = @app.PCOCallBack;
                PCOvid.FramesAcquiredFcn = @app.PCOCallBack;
                
                %Initialize setting
                    %General
                PCOsrc.B1BinningHorizontal = '02';
                PCOsrc.B2BinningVertical = '02';
                PCOsrc.E1ExposureTime_unit = 'ms';
                PCOsrc.E2ExposureTime = 5;
                PCOsrc.IRMode = 'on';
                PCOsrc.PCPixelclock_Hz = '12000000';
                
                
                PCOsrc.TMTimestampMode = 'No Stamp';
                
                PCOvid.FramesPerTrigger = 1;
                PCOvid.ReturnedColorSpace = 'grayscale';
                %Trigger
                triggerconfig(PCOvid,'Hardware');
                PCOvid.TriggerRepeat = inf;
                PCOvid.FramesPerTrigger = 1;
                %every 3 picture will get a call back, so this can
                PCOvid.FramesAcquiredFcnCount = 3;
                
                %ROI
                %PCOvid.ROIposition = [0 0 696 520];
                %Disk logging
                PCOvid.LoggingMode = 'memory';
                %Using UserData to record filefolder and filename and others
                today = split(date,'-');
                %here we do NOT use IMAQ's ROI, instead use user's data to save ROI
                UD = struct('SelectFolder',['D:\data\' today{3} '\' today{2} '\' today{1}],...
                    'FileName1','Na',...
                    'FileName2','Rb',...
                    'Autoadd',1,...
                    'ContinueQ',1,...
                    'ROIposition',[0 0 696 520]);
                PCOvid.UserData = UD;
                
                %others
                PCOvid.TimerPeriod = 180; %one shot with more than 180 seconds is really long
                
                PCOsrc.RDIDoubleImageMode = 'on';
                
                app.Status.Value = [PCOvid.Name ' has been initialized!'];
                results = 1;
            
        end
       
        
        function cameraBrowserClose(app,~,~)
            if ~ishandle(app)
                delete(gcf);
            else
                app.subuiTree.cameraBrowser.obj = [];
                app.subuiTree.cameraBrowser.Count = 0;
                delete(gcf);
            end
        end

        function PreviewCamera(app,~,~)
            %get tree-node package
            import uiextras.jTree.*;
                        
            %get tree handle
            f = gcf;
            panel = f.Children(1);
            t = panel.UserData;
            selectedNodes = t.SelectedNodes;
            selectedNodesNumber = length(selectedNodes);
            
            for i = 1:selectedNodesNumber
                node = selectedNodes(i);
                %format = node.Name;
                cameraID = node.Parent.Value;
                adaptor = node.Parent.Parent.Name;
                %get camera handles, put into vid
                selectedvid = app.vid.([adaptor '_' num2str(cameraID)]);
                %selectedsrc = getselectedsource(selectedvid);
                if strcmp(selectedvid.TriggerType,'immediate')
                    preview(selectedvid)
                else
                    app.Status.Value = ['Please set ' selectedvid.Name ' trigger mode to Immediate'];
                end
            end
        end
        
        function results = CutLog(~,x)
            % this function set x>0.82 to log x, and set x<0.82 to 0, and set x=Inf to 0
            
            if (x > 10000)
                results = 0;
            else
                %temp = x.*(x > 0.1);
                temp = x.*(x > 0.82);
                results = log(temp+(temp == 0));
            end
        end
        
        function OD = getOD(~,atom,light,dark)
%             %'1000 * Log(Abs((light(i, j) - dark(i, j)) / (shadow(i, j) - dark(i, j))))
%             if (shadow(i, j) ~= darkS(i, j)) && Math.Sign(light(i, j) - darkL(i, j)) = Math.Sign(shadow(i, j) - darkS(i, j))
%                 ddata(i, j) = CLng(1000 * log((light(i, j) - darkL(i, j)) / (shadow(i, j) - darkS(i, j))));
%                 if ddata(i, j) <= CLng(-200)
%                     ddata(i, j) = CLng(200);
%                 else
%                     ddata(i, j) = CLng(ddata(i, j) + 200);
%                 end
%             else
%                 ddata(i, j) = 200;
%            end
        end
        
    end
    
    methods (Access = private)
        
        function BFCallBack(app,obj,event)
            errID = 'imaq:imaqcallback:invalidSyntax';
            errID2 = 'imaq:imaqcallback:zeroInputs';
            
            switch nargin
                case 0
                    error(message(errID2));
                case 1
                    error(message(errID));
                case 2
                    if ~isa(obj, 'imaqdevice') || ~isa(event, 'struct')
                        error(message(errID));
                    end
                    if ~(isfield(event, 'Type') && isfield(event, 'Data'))
                        error(message(errID));
                    end
            end
            
            % Determine the type of event.
            EventType = event.Type;
            
            % Determine the time of the error event.
            EventData = event.Data;
            EventDataTime = EventData.AbsTime;
            
            % Create a display indicating the type of event, the time of the event and
            % the name of the object.
            name = get(obj, 'Name');
            nameSP = split(name,'-');
            cameraName = [nameSP{2} '_' nameSP{3}];
            % show callback info on app.Status
            switch EventType
                case 'Start'
                    app.Status.Value = [name ' start at ' datestr(EventDataTime,13)...
                        ' , waiting for trigger 1/' num2str(obj.FramesAcquiredFcnCount)];
                case 'Trigger'
                    app.Status.Value = [name ' is triggered at ' datestr(EventDataTime,13)...
                        ' , waiting for trigger ' num2str(obj.FramesAvailable+1) '/' num2str(obj.FramesAcquiredFcnCount)];
                case 'Stop'
                    app.Status.Value = [EventType ' event occurred at ' datestr(EventDataTime,13) ' for video input object: ' name];
                case 'FramesAcquired'
                    app.Status.Value = [EventType ' event occurred at ' datestr(EventDataTime,13) ' for video input object: ' name];
                    
                    %get data from vid
                    for i = 1:obj.FramesAvailable
                        tempData = getdata(obj);
                        app.img.(cameraName).(['IMG' num2str(i)]) = tempData/16; %for 12bit BF camera
                    end
                    
                    %modify? Light picture
                    if isfield(app.selectedimast,([(cameraName)]))
                        vuima = app.selectedimast.([(cameraName)]);
                        if(vuima.LModify.Value == 1)
                            x1=vuima.Lx.Value;
                            x2=x1+vuima.W.Value;
                            y1=vuima.Uy.Value;
                            y2=y1+vuima.H.Value;
                            IMGtemp_1 = double(app.img.([(cameraName)]).IMG1);
                            IMGtemp_2 = double(app.img.([(cameraName)]).IMG2);
                            Mean1 = mean(mean(IMGtemp_1(y1:y2,x1:x2)));
                            Mean2 = mean(mean(IMGtemp_2(y1:y2,x1:x2)));
                            app.img.([(cameraName)]).IMG2 = uint16(double(app.img.([(cameraName)]).IMG2) * (Mean1 /Mean2));
                        end
                    end
                    
                    %Method from OLD VB program to get OD


                    %get OD
                    %                     app.img.(cameraName).IMGOD =...
                    %                         CutLog(app,double(app.img.(cameraName).IMG2) - double(app.img.(cameraName).IMG3))-...
                    %                         CutLog(app,double(app.img.(cameraName).IMG1) - double(app.img.(cameraName).IMG3));
                    
                    %get OD
                    
                    if isfield(app.selectedimast,(cameraName))
                        
                        Csat = app.selectedimast.(cameraName).Csat.Value;
                        Bin = app.selectedimast.(cameraName).Bin.Value;
                        Expo = app.selectedimast.(cameraName).Expo.Value;
                        %if Condi is 1, add modification, if Condi is 0, no modification
                        Condi = app.selectedimast.(cameraName).Condi.Value;
                        app.img.(cameraName).IMGOD =...
                            200 + 1000*(CutLog(app,(double(app.img.(cameraName).IMG2) - double(app.img.(cameraName).IMG3))./...
                            (double(app.img.(cameraName).IMG1) - double(app.img.(cameraName).IMG3))) + ...
                            Condi * (double(app.img.(cameraName).IMG2) - double(app.img.(cameraName).IMG1))/(Expo*(Bin^2)*Csat));
                        
                    end

                    
                    %show OD  and  do fitting and show fitting result
                    if isfield(app.selectedimast,(cameraName))
                        %get vuima
                        vuima = app.selectedimast.(cameraName);
                        %transfer data
                        vuima.curimage = app.img.(cameraName).IMGOD;
                        vuima.otherimage.IMG1 = app.img.(cameraName).IMG1;
                        vuima.otherimage.IMG2 = app.img.(cameraName).IMG2;
                        vuima.otherimage.IMG3 = app.img.(cameraName).IMG3;
                        
                        %write binning info to vuima
                        %get videoresolution
                        src = getselectedsource(obj);
                        vuima.curBin.x = double(src.BinningHorizontal);
                        vuima.curBin.y = double(src.BinningVertical);
                        
                        %show mean of light picture 
                        MeanLight = mean(mean(vuima.otherimage.IMG2)) - mean(mean(vuima.otherimage.IMG3));
                        vuima.Light.Value = MeanLight;
                        
                        %and show counting rate
                        Expo = app.selectedimast.(cameraName).Expo.Value;
                        Bin = app.selectedimast.(cameraName).Bin.Value;
                        vuima.CountRate.Value = MeanLight / (Expo * Bin^2);
                        
                        %set show aspect ratio
                        imgsize = size(app.img.(cameraName).IMGOD);
                        vuima.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                        %imshow(app.img.(cameraName).IMGOD,'Parent',vuima.ImageShow,'XData',[1 imgsize(2)],'YData',[1 imgsize(1)]);
                        imagesc(vuima.ImageShow,app.img.(cameraName).IMGOD);
                        %chnge colormap to jet
                        colormap(vuima.ImageShow,jet);
                        colorbar(vuima.ImageShow,'southoutside');
                    
                        %saving
                        if vuima.AutoSave.Value
                            vuima.saveimage();
                        end
                        
                        %fitting
                        if vuima.Auto.Value
                            vuima.fit();
                        end

                    end
                    
                case 'Timer'
                    app.Status.Value = 'Wooo... its really a long experiment! Be patient, Dude.';
            end
            % Display the error string.
            if strcmpi(EventType, 'error')
                app.Status.Value = EventData.Message;
            end            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %reset all cameras
            app.ResetAllCameras();
            %Searcha and get all available cameras, put in app.cameraTree
            app.SearchAndGetCameras();
            
            %Start a Java Robot for external control of mouse and keyboard
            import java.awt.Robot;
            import java.awt.event.*;
            app.robot = java.awt.Robot;
        end

        % Callback function
        function CamimaMainSizeChanged(app, event)
           % h = gcbo;
           % position = h.Position;
            position = app.CamimaMain.Position;
            app.Status.Position = [0 0 position(3) 22];
            app.MainTabGroup.Position = [0 22 position(3) position(4)-22];
        end

        % Callback function
        function HOMETabSizeChanged(app, event)
            position = app.HOMETab.Position;
            if ~isempty(app.CameraPanel)
                app.CameraPanel.Position = [0 0 position(3)/5 position(4)];
            end
            if ~isempty(app.ImastPanel)
                app.ImastPanel.Position = [position(3)/5 0 position(3)/5 position(4)];
            end
            if ~isempty(app.ControlPanel)
                app.ControlPanel.Position = [position(3)*2/5 0 position(3)*2/5 position(4)];
            end
        end

        % Callback function
        function CameraPanelSizeChanged(app, event)
            position = app.CameraPanel.Position;
            if ~isempty(app.CameraBrowserButton)
                app.CameraBrowserButton.Position = [5 5 (position(3)-15)/2 position(4)-30];
            end
            if ~isempty(app.CameraSettingButton)
                app.CameraSettingButton.Position = [(position(3)-15)/2+10 5 (position(3)-15)/2 position(4)-30];
            end
        end

        % Button pushed function: CameraBrowserButton
        function CameraBrowserButtonPushed(app, event)
            position = app.CamimaMain.Position;
            cameraBrowser(app,position);
        end

        % Callback function
        function ImastPanelSizeChanged(app, event)
            position = app.ImastPanel.Position;
            if ~isempty(app.NewVuimaButton)
                app.NewVuimaButton.Position = [5 5 (position(3)-15)/2 position(4)-30];
            end
        end

        % Button pushed function: NewVuimaButton
        function NewVuimaButtonPushed(app, event)
            %position = app.CamimaMain.Position;
            vuima = Vuima;
            if isfield(app.subuiTree,'vuima')
                app.subuiTree.vuima.obj = [app.subuiTree.vuima.obj,{vuima}];
                app.subuiTree.vuima.Count = app.subuiTree.vuima.Count + 1;
            else
                app.subuiTree.vuima.obj = {vuima};
                app.subuiTree.vuima.Count = 1;
            end
            
            %send vuima camima's handle
            vuima.camima = app;
            
            %initial vuima
            vuima.imastDropDown.Enable = 'on';
            vuima.LinkButton.Enable = 'on';
            
            if ~isempty(app.imast)
                %send image stream
                vuima.imast = app.imast;
                vuima.imastDropDown.Items = app.imast;
            end
            
        end

        % Button pushed function: CameraSettingButton
        function CameraSettingButtonPushed(app, event)
            camset = Camset; % New Camset
            
            if isfield(app.subuiTree,'camset')
                app.subuiTree.camset.obj = [app.subuiTree.camset.obj,{camset}];
                app.subuiTree.camset.Count = app.subuiTree.camset.Count + 1;
            else  
                app.subuiTree.camset.obj = {camset};
                app.subuiTree.camset.Count = 1;
            end
            
            %send vuima camima's handle
            camset.camima = app;
            
            %send all available camera handles
            camset.vid = app.vid;
                        
            %initial camset
            camset.CameraBrowserButton.Enable = 'off';
            camset.InitializeButton.Enable = 'off';
            camset.CameraDropDown.Enable = 'on';
            camset.SetButton.Enable = 'on';
            tempvid = app.vid;
            camset.CameraDropDown.Items = fieldnames(tempvid);
            
        end

        % Close request function: CamimaMain
        function CamimaMainCloseRequest(app, event)
            if ~isempty(app.subuiTree)
                %delete all subui
                subuiName = fieldnames(app.subuiTree);
                subuiNumber = length(subuiName);
                
                for i = 1:subuiNumber
                    subuiModel = app.subuiTree.(subuiName{i});
                    subuiModelHandle = subuiModel.obj;
                    if ~ishandle(subuiModel)
                        if iscell(subuiModelHandle)
                            for j = 1:subuiModel.Count
                                %delete all subui
                                if isvalid(subuiModelHandle{j})
                                    delete(subuiModelHandle{j});
                                end
                            end
                        else
                            if isvalid(subuiModelHandle)
                                delete(subuiModelHandle);
                            end
                        end
                    else
                        if isvalid(subuiModel)
                            delete(subuiModel);
                        end
                    end
                end
            end
            %delete itself
            delete(app);
            
        end

        % Callback function
        function ControlPanelSizeChanged(app, event)
            position = app.ControlPanel.Position;
            
            if ~isempty(app.StartAllButton)
                app.StartAllButton.Position = [5 5 (position(3)-25)/4 position(4)-30];
            end
            if ~isempty(app.StopAllButton)
                app.StopAllButton.Position = [(position(3)-25)/4+10 5 (position(3)-25)/4 position(4)-30];
            end
        end

        % Button pushed function: StartAllButton
        function StartAllButtonPushed(app, event)
            vidName = fieldnames(app.vid);
            vidNumber = length(vidName);
            for i = 1:vidNumber
                start(app.vid.(vidName{i}));
            end
            app.StartAllButton.Enable = 'off';
            app.StopAllButton.Enable = 'on';
            app.Status.Value = 'All Camera started!';
        end

        % Button pushed function: StopAllButton
        function StopAllButtonPushed(app, event)
            vidName = fieldnames(app.vid);
            vidNumber = length(vidName);
            for i = 1:vidNumber
                stop(app.vid.(vidName{i}));
            end
            app.StopAllButton.Enable = 'off';
            app.StartAllButton.Enable = 'on';
            app.Status.Value = 'All Camera stopped!';
        end

        % Button pushed function: TestClick
        function TestClickButtonPushed(app, event)
            XautoClick = app.AutoClickX.Value;
            YautoClick = app.AutoClickY.Value;
            app.robot.mouseMove(XautoClick,YautoClick);
            %app.robot.mousePress(java.awt.event.InputEvent.BUTTON1_MASK);
            java.awt.event.InputEvent.BUTTON1_MASK;
            %app.robot.mouseRelease (java.awt.event.InputEvent.BUTTON1_MASK);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CamimaMain = uifigure('Visible', 'off');
            app.CamimaMain.Name = 'Camima';
            app.CamimaMain.CloseRequestFcn = createCallbackFcn(app, @CamimaMainCloseRequest, true);
            app.Toolbar = uitoolbar(app.CamimaMain);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.PushTool3 = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.CamimaMain);
            app.MainTabGroup = uitabgroup(app.GridLayout);
            app.HOMETab = uitab(app.MainTabGroup);
            app.HOMETab.Title = 'HOME';
            app.GridLayout2 = uigridlayout(app.HOMETab);
            app.CameraPanel = uipanel(app.GridLayout2);
            app.CameraPanel.Title = 'Camera';
            app.GridLayout3 = uigridlayout(app.CameraPanel);
            app.CameraBrowserButton = uibutton(app.GridLayout3, 'push');
            app.CameraBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @CameraBrowserButtonPushed, true);
            app.CameraBrowserButton.Icon = 'cameraIcon.png';
            app.CameraBrowserButton.Text = {'Camera'; 'Browser'};
            app.CameraSettingButton = uibutton(app.GridLayout3, 'push');
            app.CameraSettingButton.ButtonPushedFcn = createCallbackFcn(app, @CameraSettingButtonPushed, true);
            app.CameraSettingButton.Icon = 'setting.png';
            app.CameraSettingButton.Enable = 'off';
            app.CameraSettingButton.Text = {'Camera'; 'Setting'};
            app.ImastPanel = uipanel(app.GridLayout2);
            app.ImastPanel.Title = 'Image Stream';
            app.GridLayout4 = uigridlayout(app.ImastPanel);
            app.NewVuimaButton = uibutton(app.GridLayout4, 'push');
            app.NewVuimaButton.ButtonPushedFcn = createCallbackFcn(app, @NewVuimaButtonPushed, true);
            app.NewVuimaButton.Icon = 'Vuima.png';
            app.NewVuimaButton.Text = {'New'; 'Vuima'};
            app.ControlPanel = uipanel(app.GridLayout2);
            app.ControlPanel.Title = 'Control';
            app.GridLayout5 = uigridlayout(app.ControlPanel);
            app.StartAllButton = uibutton(app.GridLayout5, 'push');
            app.StartAllButton.ButtonPushedFcn = createCallbackFcn(app, @StartAllButtonPushed, true);
            app.StartAllButton.Icon = 'Start.png';
            app.StartAllButton.Enable = 'off';
            app.StartAllButton.Text = {'Start'; 'All'};
            app.StopAllButton = uibutton(app.GridLayout5, 'push');
            app.StopAllButton.ButtonPushedFcn = createCallbackFcn(app, @StopAllButtonPushed, true);
            app.StopAllButton.Icon = 'Stop.png';
            app.StopAllButton.Enable = 'off';
            app.StopAllButton.Text = {'Stop'; 'All'};
            app.TestClick = uibutton(app.GridLayout5, 'push');
            app.TestClick.ButtonPushedFcn = createCallbackFcn(app, @TestClickButtonPushed, true);
            app.TestClick.Text = {'Test'; 'Click'};
            app.AutoClick = uicheckbox(app.GridLayout5);
            app.AutoClick.Text = 'Auto Click';
            app.AutoClickX = uieditfield(app.GridLayout5, 'numeric');
            app.XEditFieldLabel = uilabel(app.GridLayout5);
            app.XEditFieldLabel.Text = 'X';
            app.AutoClickY = uieditfield(app.GridLayout5, 'numeric');
            app.YEditFieldLabel = uilabel(app.GridLayout5);
            app.YEditFieldLabel.Text = 'Y';
            app.MoreTab = uitab(app.MainTabGroup);
            app.MoreTab.Title = 'More...';
            app.PCOsingleimageEditFieldLabel = uilabel(app.MoreTab);
            app.PCOsingleimageEditFieldLabel.Text = 'PCO single image';
            app.PCOsingleimage = uieditfield(app.MoreTab, 'numeric');
            app.PCOsingleimage.Limits = [0 1];
            app.PCOsingleimage.ValueDisplayFormat = '%.0f';
            app.Status = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.CamimaMain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Camima

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CamimaMain)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CamimaMain)
        end
    end
end