classdef Vuima < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VuimaMain                     matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        PushTool2                     matlab.ui.container.toolbar.PushTool
        PushTool3                     matlab.ui.container.toolbar.PushTool
        PushTool4                     matlab.ui.container.toolbar.PushTool
        GridLayout                    matlab.ui.container.GridLayout
        FittingSettingPanel           matlab.ui.container.Panel
        Auto                          matlab.ui.control.CheckBox
        FitButton                     matlab.ui.control.Button
        FittingModeDropDown           matlab.ui.control.DropDown
        FittingSettingButton          matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        pksSpinnerLabel               matlab.ui.control.Label
        pks                           matlab.ui.control.Spinner
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Status                        matlab.ui.control.EditField
        odValue                       matlab.ui.control.NumericEditField
        CountRate                     matlab.ui.control.NumericEditField
        Light                         matlab.ui.control.NumericEditField
        QuickFitting                  matlab.ui.container.Panel
        peakEditFieldLabel            matlab.ui.control.Label
        peak                          matlab.ui.control.NumericEditField
        xcEditFieldLabel              matlab.ui.control.Label
        xc                            matlab.ui.control.NumericEditField
        ycEditFieldLabel              matlab.ui.control.Label
        yc                            matlab.ui.control.NumericEditField
        sigxEditFieldLabel            matlab.ui.control.Label
        sigx                          matlab.ui.control.NumericEditField
        sigyEditFieldLabel            matlab.ui.control.Label
        sigy                          matlab.ui.control.NumericEditField
        peak_2                        matlab.ui.control.NumericEditField
        xc_2                          matlab.ui.control.NumericEditField
        yc_2                          matlab.ui.control.NumericEditField
        sigx_2                        matlab.ui.control.NumericEditField
        sigy_2                        matlab.ui.control.NumericEditField
        AutoIniFit                    matlab.ui.control.CheckBox
        ErrorData                     matlab.ui.control.Table
        FittingData                   matlab.ui.control.Table
        MainTabGroup                  matlab.ui.container.TabGroup
        HomeTab                       matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        LinkingPanel                  matlab.ui.container.Panel
        LinkingLamp                   matlab.ui.control.Lamp
        LinkButton                    matlab.ui.control.Button
        imastDropDownLabel            matlab.ui.control.Label
        imastDropDown                 matlab.ui.control.DropDown
        CheckImagePanel               matlab.ui.container.Panel
        DropDown                      matlab.ui.control.DropDown
        CheckButton                   matlab.ui.control.Button
        Spinner                       matlab.ui.control.Spinner
        ImJButton                     matlab.ui.control.Button
        LUTDropDown                   matlab.ui.control.DropDown
        SavingPanel                   matlab.ui.container.Panel
        Browser                       matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        AutoSave                      matlab.ui.control.CheckBox
        SelectFolderEditFieldPGLabel  matlab.ui.control.Label
        SelectFolder                  matlab.ui.control.EditField
        FileNameEditFieldPGLabel      matlab.ui.control.Label
        FileName                      matlab.ui.control.EditField
        ViewTab                       matlab.ui.container.Tab
        HighIntensityImageModify      matlab.ui.container.Panel
        expoEditFieldLabel            matlab.ui.control.Label
        Expo                          matlab.ui.control.NumericEditField
        BinEditFieldLabel             matlab.ui.control.Label
        Bin                           matlab.ui.control.NumericEditField
        ModifyEditFieldLabel          matlab.ui.control.Label
        Condi                         matlab.ui.control.NumericEditField
        CsatEditFieldLabel            matlab.ui.control.Label
        Csat                          matlab.ui.control.NumericEditField
        ProbeLightModify              matlab.ui.container.Panel
        LxEditFieldLabel              matlab.ui.control.Label
        Lx                            matlab.ui.control.NumericEditField
        WEditFieldLabel               matlab.ui.control.Label
        W                             matlab.ui.control.NumericEditField
        UyEditFieldLabel              matlab.ui.control.Label
        Uy                            matlab.ui.control.NumericEditField
        HEditFieldLabel               matlab.ui.control.Label
        H                             matlab.ui.control.NumericEditField
        LModifyEditFieldLabel         matlab.ui.control.Label
        LModify                       matlab.ui.control.NumericEditField
        CountNumberPanel              matlab.ui.container.Panel
        CountNumberButton             matlab.ui.control.Button
        TotalODEditFieldLabel         matlab.ui.control.Label
        TotalODEditField              matlab.ui.control.NumericEditField
        NumberEditFieldLabel          matlab.ui.control.Label
        NumberEditField               matlab.ui.control.NumericEditField
        AutoCountButtun               matlab.ui.control.CheckBox
        GaussianODEditFieldLabel      matlab.ui.control.Label
        GaussianODEditField           matlab.ui.control.NumericEditField
        tmsigEditFieldLabel           matlab.ui.control.Label
        tmsigEditField                matlab.ui.control.NumericEditField
        ReODPanel                     matlab.ui.container.Panel
        ReODButton_2                  matlab.ui.control.Button
        BatchTab                      matlab.ui.container.Tab
        BatchListBrowser              matlab.ui.control.Button
        SelectFileListLabel           matlab.ui.control.Label
        BatchList                     matlab.ui.control.EditField
        FittingModeDropDown_1         matlab.ui.control.DropDown
        FittingModeDropDown_2         matlab.ui.control.DropDown
        FittingModeDropDown_3         matlab.ui.control.DropDown
        Gauge                         matlab.ui.control.LinearGauge
        BatchListBrowser_2            matlab.ui.control.Button
        ImageFolderLabel              matlab.ui.control.Label
        BatchList_2                   matlab.ui.control.EditField
        BatchListBrowser_3            matlab.ui.control.Button
        OutputFolderLabel             matlab.ui.control.Label
        BatchList_3                   matlab.ui.control.EditField
        FittingModeDropDown_4         matlab.ui.control.DropDown
        FittingModeDropDown_5         matlab.ui.control.DropDown
        FittingModeDropDown_6         matlab.ui.control.DropDown
        FittingModeDropDown_7         matlab.ui.control.DropDown
        FittingModeDropDown_8         matlab.ui.control.DropDown
        NoofModelsSpinnerLabel        matlab.ui.control.Label
        NoofModelsSpinner             matlab.ui.control.Spinner
        ProcessingLampLabel           matlab.ui.control.Label
        ProcessingLamp                matlab.ui.control.Lamp
        StopButton_2                  matlab.ui.control.Button
        FitallButton                  matlab.ui.control.Button
        PauseButton_2                 matlab.ui.control.StateButton
        FitResultFileNameEditFieldLabel  matlab.ui.control.Label
        FitResultFileNameEditField    matlab.ui.control.EditField
        ExportImageButton             matlab.ui.control.Button
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        CountnumberButton             matlab.ui.control.Button
        ReODButton                    matlab.ui.control.Button
        fittingX                      matlab.ui.control.UIAxes
        fittingY                      matlab.ui.control.UIAxes
        ImageShow                     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        camima % main camima handle
        imast % image stream
        curimage %present image data, used to show and do fitting
        nextimage % a counter for next picture group coming in
        Equation % fit equation
        opt % fit options
        otherimage % is a struct including UserDifined images, like BG, Light, Atom and more
        curFileNum %current file number
        curBin %binning of current image
        curfittingresult % current fitting result
        curfittinggof % current fitting gof, for showoing error of fitting
    end

    properties (Access = private)
    end
    
    methods (Access = public)
        
        function [fitresult, gof] = dofit(~,XData, YData, ZData, equation, opts)
            %% Fit
            [xData, yData, zData] = prepareSurfaceData(XData, YData, ZData);
            
            % Set up fittype and options.
            ft = fittype(equation{1}, 'independent', {'x', 'y'}, 'dependent', 'z' );
                                    
            % Fit model to data.
            [fitresult, gof] = fit( [xData, yData], zData, ft, opts );
            
            %make fitting parallel
            %f = parfeval(@fit,2,[xData, yData], zData, ft, opts);
            %[fitresult, gof] = fetchOutputs(f);
        end
        

        function [Xdata, Ydata, Zdata, peak, xc, yc, xs, ys] = image2data(~, data)
            %data = app.curimage;
            [xs,ys] = size(data);           
            Zdata = data(:)';         
            Xdata = repmat(1:xs,1,ys);
            Ydata = repelem(1:ys,xs);
            % peak and canter more info from preprocess
            [peak,peakPosition] = max(data(:));
            [xc,yc] = ind2sub(size(data),peakPosition);
        end
        
        function fit(app)
           %run fitting in parallel
           
           %run fitting with GPU and more
           
           %parfeval(@fitCore,0,app);
           fitCore(app);
        end
        
        function fitCore(app)
            %get model to fitting
            model = app.FittingModeDropDown.Value;
            
            switch model
                case 'Customed'
                    fitCustomed(app);
                case 'Gaussian'
                    fitGaussian(app);
                case 'Multi-Gaussian'
                    fitMultiGaussian(app);
                case 'DG-Concentric'
                    fitDGConcentric(app);
                case 'Parabola'
                    fitParabola(app);
                case 'ParabolaGaussian'
                    ParabolaGaussian(app);
                case 'DG'
                    fitDoubleGaussian(app);
            end            
        end
        
        function saveimage(app)
            
            imwrite(app.otherimage.IMG1,[app.SelectFolder.Value '\' num2str(app.nextimage,'%03d') '-' app.FileName.Value '-1.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            imwrite(app.otherimage.IMG2,[app.SelectFolder.Value '\' num2str(app.nextimage,'%03d') '-' app.FileName.Value '-2.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            imwrite(app.otherimage.IMG3,[app.SelectFolder.Value '\' num2str(app.nextimage,'%03d') '-' app.FileName.Value '-3.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            
            imwrite(uint16(app.curimage),[app.SelectFolder.Value '\' num2str(app.nextimage,'%03d') '-' app.FileName.Value '-OD.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            %refresh spinner
            app.curFileNum = app.nextimage;
            app.Spinner.Value = app.curFileNum;
            app.nextimage = app.nextimage + 1;
        end
        
        function fitGaussian(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
           
            %set gaussian equation
            equation = {'bg + peak * exp( - 0.5* (x-xc)^2 / sigx^2 -0.5* (y-yc)^2/sigy^2)'};
            
            %set local equation to app.Equation
            app.Equation = equation;
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [10000 1e05 Inf Inf xs ys];
            opts.Lower = [0 30 0 0 1 1];
            
            if app.AutoIniFit.Value
                opts.StartPoint = [200 peak 10 10 xc yc];
            else
                opts.StartPoint = [200 app.peak.Value app.sigy.Value app.sigx.Value app.yc.Value app.xc.Value];
            end
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 30;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %set local fitting opts to app.opt
            app.opt = opts;
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilename = [app.SelectFolder.Value '\' app.FileName.Value '.csv'];            
            dlmwrite(fitfilename,[app.Expo.Value,app.curFileNum,coValue(2),app.curBin.x*coValue(4),app.curBin.y*coValue(3),coValue(6),coValue(5),app.Light.Value],'-append');
            
            
            %show original od for checking whether this picture is out of range of probe light couinting
            %show original o.d.
            syms od;
            CountRat = app.CountRate.Value;
            CountSat = app.Csat.Value;
            OD = coValue(2)/1000;
            eqn = OD == od + (1 - exp(-od)) * CountRat / CountSat;
            solod = solve(eqn,od);
            app.odValue.Value = double(solod)*1000;
            
        end
        
        function fitCustomed(app)            
            %prepare data
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, app.Equation, app.opt);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,xs,ys,xc,yc);
        end
        
        function fitMultiGaussian(app)
            %get number of peaks
            pks = app.pks.Value;
            
            % 
        end
        
        function fitDGConcentric(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %set initial peakA and peakB
            peakA = peak*4/5;
            peakB = peak/5;   % here we use a guess of one third total peak to get lower gaussian's peak
            
            %sigxA and sigyA    and    sigxB and sigyB
            sigxA = 1.5;
            sigyA = 1.5;
            
            sigxB = 7;
            sigyB = 7;
            
            %set gaussian equation
            equation = {'bg + peakA * exp( - 0.5* (x-xcA)^2 / sigxA^2 -0.5* (y-ycA)^2/sigyA^2) + peakB * exp( - 0.5* (x-xcB)^2 / sigxB^2 -0.5* (y-ycB)^2/sigyB^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [1000 1e05 1e05 Inf Inf Inf Inf xs xs ys ys];
            opts.Lower = [0 30 30 0 0 0 0 1 1 1 1];
            opts.StartPoint = [200 peakA peakB sigxA sigxB sigyA sigyB xc xc yc yc];  %because of concentric we set same xc and yc for two gaussian
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 20;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilenameA = [app.SelectFolder.Value '\' app.FileName.Value 'A.csv'];
            fitfilenameB = [app.SelectFolder.Value '\' app.FileName.Value 'B.csv'];
            dlmwrite(fitfilenameA,[0,app.curFileNum,coValue(2),app.curBin.x*coValue(6),app.curBin.y*coValue(4),coValue(10),coValue(8),app.Light.Value],'-append');
            dlmwrite(fitfilenameB,[0,app.curFileNum,coValue(3),app.curBin.x*coValue(7),app.curBin.y*coValue(5),coValue(11),coValue(9),app.Light.Value],'-append');            

            
            %show original od for checking whether this picture is out of range of probe light couinting
            %show original o.d.
            syms od;
            CountRat = app.CountRate.Value;
            CountSat = app.Csat.Value;
            OD = (coValue(2) + coValue(3))/1000;
            eqn = OD == od + (1 - exp(-od)) * CountRat / CountSat;
            solod = solve(eqn,od);
            app.odValue.Value = double(solod)*1000;
            
        end        
        
        
        function showFit(app,fitresult,gof,xs,ys,xc,yc)
            %reconstruct function and data for showing
            coNumber = numcoeffs(fitresult);
            coName = coeffnames(fitresult);
            coValue = coeffvalues(fitresult);
            
            % show fitting result
            app.FittingData.Data = [coName num2cell(coValue')];
            
            %reconstruct error for showing in list
            ErrName = fieldnames(gof);
            ErrValue = cellfun(@(f)num2cell(getfield(gof,f)),ErrName);
            
            %show error result
            app.ErrorData.Data = [ErrName ErrValue];
            
            %construct coeff string
            costr = coName{1};
            if coNumber > 1
                for i = 2:coNumber
                    costr = [costr ',' coName{i}];
                end
            end
            
            %build function for plot
            fy = str2func(vectorize(['@(x) @(' costr ') @(y)' formula(fitresult)]));
            fx = str2func(vectorize(['@(y) @(' costr ') @(x)' formula(fitresult)]));

            %plot x-direction
            fytemp = fy(xc);
            arg = num2cell(coValue);
            f = fytemp(arg{:});     
            app.fittingX.XLim = [0.5 ys+0.5];
            plot(app.fittingX,1:ys,f(1:ys),1:ys,app.curimage(xc,:));
            
            %plot y-direction
            fxtemp = fx(yc);
            arg = num2cell(coValue);
            f = fxtemp(arg{:});           
            app.fittingY.YLim = [0.5 xs+0.5];
            app.fittingY.YDir = 'reverse';
            plot(app.fittingY,f(1:xs),1:xs,app.curimage(:,yc)',1:xs);
        end
        
        function fitParabola(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %Rx and Ry
            Rx = 10;
            Ry = 10;
                        
            %set equation
            equation = {'bg + peak * max((1 - ((x-xc)/Rx)^2 - ((y-yc)/Ry)^2), 0)^1.5'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [Inf Inf 1000 1e05 xs ys];
            opts.Lower = [0 0 0 30 1 1];
            opts.StartPoint = [Rx Ry 200 peak xc yc];
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 30;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilename = [app.SelectFolder.Value '\' app.FileName.Value '.csv'];            
            dlmwrite(fitfilename,[0,app.curFileNum,coValue(4),app.curBin.x*coValue(2),app.curBin.y*coValue(1),coValue(6),coValue(5),app.Light.Value],'-append');
            
            %show original od for checking whether this picture is out of range of probe light couinting
            %show original o.d.
            syms od;
            CountRat = app.CountRate.Value;
            CountSat = app.Csat.Value;
            OD = coValue(4)/1000;
            eqn = OD == od + (1 - exp(-od)) * CountRat / CountSat;
            solod = solve(eqn,od);
            app.odValue.Value = double(solod)*1000;
        end
        
        function results = cutoffAboveZero(~, x)
            %not used now
            
            if (x>0)
                results = x;
            else
                results = 0;
            end
        end
        
        function fitAiry(app)
            
            
        end
       
        function fitDoubleGaussian(app)
            
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);

            %set gaussian equation
            equation = {'bg + peakA * exp( - 0.5* (x-xcA)^2 / sigxA^2 -0.5* (y-ycA)^2/sigyA^2) + peakB * exp( - 0.5* (x-xcB)^2 / sigxB^2 -0.5* (y-ycB)^2/sigyB^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [1000 1e05 1e05 Inf Inf Inf Inf xs xs ys ys];
            opts.Lower = [0 30 30 0 0 0 0 1 1 1 1];
            opts.StartPoint = [200 app.peak.Value app.peak_2.Value app.sigy.Value app.sigy_2.Value app.sigx.Value app.sigx_2.Value app.yc.Value app.yc_2.Value app.xc.Value app.xc_2.Value];  %because of concentric we set same xc and yc for two gaussian
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 20;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilenameA = [app.SelectFolder.Value '\' app.FileName.Value 'A.csv'];
            fitfilenameB = [app.SelectFolder.Value '\' app.FileName.Value 'B.csv'];
            dlmwrite(fitfilenameA,[0,app.curFileNum,coValue(2),app.curBin.x*coValue(6),app.curBin.y*coValue(4),coValue(10),coValue(8),app.Light.Value],'-append');
            dlmwrite(fitfilenameB,[0,app.curFileNum,coValue(3),app.curBin.x*coValue(7),app.curBin.y*coValue(5),coValue(11),coValue(9),app.Light.Value],'-append');            

        end
        
        function ParabolaGaussian(app)
            
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %set initial peakA and peakB
            peakA = peak*4/5;
            peakB = peak*3/5;   % here we use a guess of one third total peak to get lower gaussian's peak
            
            %sigxA and sigyA    and    sigxB and sigyB
            Rx = 40;
            Ry = 40;
            
            sigxB = 30;
            sigyB = 30;
            
            %set gaussian equation
            equation = {'bg + peakA * max((1 - ((x-xcA)/Rx)^2 - ((y-ycA)/Ry)^2), 0)^1.5 + peakB * exp( - 0.5* (x-xcB)^2 / sigxB^2 -0.5* (y-ycB)^2/sigyB^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [Inf Inf 1000 1e05 1e05 Inf Inf xs xs ys ys];
            opts.Lower = [0 0 0 30 30 0 0 1 1 1 1];
            
            if app.AutoIniFit.Value
                opts.StartPoint = [Rx Ry 200 peakA peakB sigxB sigyB xc xc yc yc];  %because of concentric we set same xc and yc for two gaussian
            else
                opts.StartPoint = [app.sigy.Value app.sigx.Value 200 app.peak.Value app.peak_2.Value app.sigy_2.Value app.sigx_2.Value app.yc.Value app.yc_2.Value app.xc.Value app.xc_2.Value];
            end


            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 30;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilenameA = [app.SelectFolder.Value '\' app.FileName.Value 'A.csv'];
            fitfilenameB = [app.SelectFolder.Value '\' app.FileName.Value 'B.csv'];
            dlmwrite(fitfilenameA,[0,app.curFileNum,coValue(4),app.curBin.x*coValue(2),app.curBin.y*coValue(1),coValue(10),coValue(8),app.Light.Value],'-append');
            dlmwrite(fitfilenameB,[0,app.curFileNum,coValue(5),app.curBin.x*coValue(7),app.curBin.y*coValue(6),coValue(11),coValue(9),app.Light.Value],'-append');            

        end
        
        function filemonthtxt = monthnum2monthchar(app, filemonth)
            switch filemonth
                case 1
                    filemonthtxt = 'Jan';
                case 2
                    filemonthtxt = 'Feb';
                case 3
                    filemonthtxt = 'Mar';
                case 4
                    filemonthtxt = 'Apr';
                case 5
                    filemonthtxt = 'May';
                case 6
                    filemonthtxt = 'Jun';
                case 7
                    filemonthtxt = 'Jul';
                case 8
                    filemonthtxt = 'Aug';
                case 9
                    filemonthtxt = 'Sep';
                case 10
                    filemonthtxt = 'Oct';
                case 11
                    filemonthtxt = 'Nov';
                case 12
                    filemonthtxt = 'Dec';
            end
        end
        
        function atomchar = atomnum2atomchar(app,atom)
            switch atom
                case 0 
                    atomchar = 'Rb';
                case 1
                    atomchar = 'Na';
            end
        end
        
        function results = fitGaussianCore(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
           
            %set gaussian equation
            equation = {'bg + peak * exp( - 0.5* (x-xc)^2 / sigx^2 -0.5* (y-yc)^2/sigy^2)'};
            
            %set local equation to app.Equation
            app.Equation = equation;
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [5000 1e05 Inf Inf xs ys];
            opts.Lower = [0 5 0 0 1 1];
            
            if app.AutoIniFit.Value
                opts.StartPoint = [3000 peak 10 10 xc yc];
            else
                opts.StartPoint = [3000 app.peak.Value app.sigy.Value app.sigx.Value app.yc.Value app.xc.Value];
            end
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 30;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %set local fitting opts to app.opt
            app.opt = opts;
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [app.curfittingresult, app.curfittinggof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,app.curfittingresult,app.curfittinggof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(app.curfittingresult);
            fitfilename = [app.BatchList_3.Value '\' app.FitResultFileNameEditField.Value '_Gaussian.csv'];
            dlmwrite(fitfilename,[app.Gauge.Value, coValue(2), app.curBin.x*coValue(4), app.curBin.y*coValue(3), coValue(6), coValue(5), app.curfittinggof.rmse, app.curfittinggof.adjrsquare],'-append');
            
        end
        
        function results = fitDGConcentricCore(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %set initial peakA and peakB
            peakA = peak*4/5;
            peakB = peak/5;   % here we use a guess of one third total peak to get lower gaussian's peak
            
            %sigxA and sigyA    and    sigxB and sigyB
            sigxA = 2;
            sigyA = 2;
            
            sigxB = 5;
            sigyB = 5;
            
            %set gaussian equation
            equation = {'bg + peakA * exp( - 0.5* (x-xcA)^2 / sigxA^2 -0.5* (y-ycA)^2/sigyA^2) + peakB * exp( - 0.5* (x-xcB)^2 / sigxB^2 -0.5* (y-ycB)^2/sigyB^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [5000 1e05 1e05 100 100 100 100 xs xs ys ys];
            opts.Lower = [0 5 5 0 0 0 0 1 1 1 1];
            opts.StartPoint = [3000 peakA peakB sigxA sigxB sigyA sigyB xc xc yc yc];  %because of concentric we set same xc and yc for two gaussian
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 20;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [app.curfittingresult, app.curfittinggof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,app.curfittingresult,app.curfittinggof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(app.curfittingresult);
            fitfilenameA = [app.BatchList_3.Value '\' app.FitResultFileNameEditField.Value '_DGConcentric_A.csv'];
            fitfilenameB = [app.BatchList_3.Value '\' app.FitResultFileNameEditField.Value '_DGConcentric_B.csv'];
            
            dlmwrite(fitfilenameA,[app.Gauge.Value, coValue(2), app.curBin.x*coValue(6), app.curBin.y*coValue(4), coValue(10), coValue(8), app.curfittinggof.rmse, app.curfittinggof.adjrsquare],'-append');
            dlmwrite(fitfilenameB,[app.Gauge.Value, coValue(3), app.curBin.x*coValue(7), app.curBin.y*coValue(5), coValue(11), coValue(9), app.curfittinggof.rmse, app.curfittinggof.adjrsquare],'-append');
            
        end
        
        function results = fitParabolaGaussianCore(app)
            
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %set initial peakA and peakB
            peakA = peak/5;
            peakB = peak*4/5;   % here we use a guess of one third total peak to get lower gaussian's peak
            
            %sigxA and sigyA    and    sigxB and sigyB
            Rx = 20;
            Ry = 20;
            
            sigxB = 4;
            sigyB = 4;
            
            %set gaussian equation
            equation = {'bg + peakA * max((1 - ((x-xcA)/Rx)^2 - ((y-ycA)/Ry)^2), 0)^1.5 + peakB * exp( - 0.5* (x-xcB)^2 / sigxB^2 -0.5* (y-ycB)^2/sigyB^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [Inf Inf 1000 1e05 1e05 Inf Inf xs xs ys ys];
            opts.Lower = [0 0 0 30 30 0 0 1 1 1 1];
            opts.StartPoint = [Rx Ry 200 peakA peakB sigxB sigyB xc xc yc yc];  %because of concentric we set same xc and yc for two gaussian
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 30;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [app.curfittingresult, app.curfittinggof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,app.curfittingresult,app.curfittinggof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(app.curfittingresult);
            fitfilenameA = [app.BatchList_3.Value '\' app.FitResultFileNameEditField.Value '_DGConcentric_A.csv'];
            fitfilenameB = [app.BatchList_3.Value '\' app.FitResultFileNameEditField.Value '_DGConcentric_B.csv'];
            
            dlmwrite(fitfilenameA,[app.Gauge.Value,app.curFileNum,coValue(4),app.curBin.x*coValue(2),app.curBin.y*coValue(1),coValue(10),coValue(8),app.curfittinggof.rmse, app.curfittinggof.adjrsquare],'-append');
            dlmwrite(fitfilenameB,[app.Gauge.Value,app.curFileNum,coValue(5),app.curBin.x*coValue(7),app.curBin.y*coValue(6),coValue(11),coValue(9),app.curfittinggof.rmse, app.curfittinggof.adjrsquare],'-append');
            
            
        end
        
        function results = fitParabolaAngle(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %Rx and Ry
            Rx = 10;
            Ry = 10;
            
            %set gaussian equation
            equation = {'bg + peak * max((1 - (((x-xc)*cos(theta)+(y-yc)*sin(theta))/Rx)^2 - (((y-yc)*cos(theta)-(x-xc)*sin(theta))/Ry)^2)),0)^1.5'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [Inf Inf 1000 1e05 Inf xs ys];
            opts.Lower = [0 0 0 30 -Inf 1 1];
            opts.StartPoint = [Rx Ry 200 peak 0 xc yc];
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 30;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [app.curfittingresult, app.curfittinggof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,app.curfittingresult,app.curfittinggof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(app.curfittingresult);
            fitfilename = [app.BatchList_3.Value '\' app.FitResultFileNameEditField.Value '_parabolaAngle.csv'];
            dlmwrite(fitfilename,[app.Gauge.Value, coValue(4), app.curBin.x*coValue(2), app.curBin.y*coValue(1), coValue(7), coValue(6), coValue(5), app.curfittinggof.rmse, app.curfittinggof.adjrsquare],'-append');
            
        end
        
        function results = countNumberCore(app,image)
            
        end
        
        function results = CutLog(~,x)
            % this function set x>0.82 to log x, and set x<0.82 to 0, and set x=Inf to 0
            
            if (x > 10000)
                results = 0;
            else
                %temp = x.*(x > 0.1);
                temp = x.*(x > 0.1);
                results = log(temp+(temp == 0));
            end
        end
        
    end

    methods (Access = private)
    
        
        function disableallFittingModeDropDown(app)
            app.FittingModeDropDown_1.Enable = 'off';app.FittingModeDropDown_5.Enable = 'off';
            app.FittingModeDropDown_2.Enable = 'off';app.FittingModeDropDown_6.Enable = 'off';
            app.FittingModeDropDown_3.Enable = 'off';app.FittingModeDropDown_7.Enable = 'off';
            app.FittingModeDropDown_4.Enable = 'off';app.FittingModeDropDown_8.Enable = 'off';
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function VuimaMainSizeChanged(app, event)
            %define sizes
            position = app.VuimaMain.Position;
            height_1 = 145;
            
            %renew app sizes
            app.Status.Position = [0 0 position(3)-height_1 22];
            app.MainTabGroup.Position = [0 position(4)-height_1 position(3) height_1];
            app.odValue.Position = [position(3)-height_1 0 50 22];
            app.CountRate.Position = [position(3)-100 0 50 22];
            app.Light.Position = [position(3)-50 0 50 22];  
            if (position(4)-22-height_1-140-174 >0)
                app.FittingData.Position = [0 336 150 position(4)-22-height_1-140-174];
            end
            app.ErrorData.Position = [0 196 150 140];
            app.QuickFitting.Position = [0 22 150 174];
            app.FittingPanel.Position = [150 22 position(3)-150 position(4)-22-height_1];
        end

        % Callback function
        function HomeTabSizeChanged(app, event)
            position = app.HomeTab.Position;
            app.LinkingPanel.Position = [0 0 150 position(4)];
            app.SavingPanel.Position = [150 0 330 position(4)];
            app.CheckImagePanel.Position = [480 0 190 position(4)];
        end

        % Callback function
        function ViewTabSizeChanged(app, event)
            position = app.ViewTab.Position;
            
            app.HighIntensityImageModify.Position = [position(1) position(2) 200 position(4)];
            app.ProbeLightModify.Position = [position(1)+200 position(2) 200 position(4)];
            app.CountNumberPanel.Position = [position(1)+200+200 position(2) 300 position(4)];
            app.ReODPanel.Position = [position(1)+200+200+300 position(2) 100 position(4)];
        end

        % Callback function
        function FittingPanelSizeChanged(app, event)
            position = app.FittingPanel.Position;
            if ~isempty(app.ImageShow)
                app.ImageShow.Position = [0 130 position(3)-130 position(4)-130];
            end
            
            if ~isempty(app.fittingX)
                app.fittingX.Position = [0 0 position(3)-130 130];
            end
            if ~isempty(app.fittingY)
                app.fittingY.Position = [position(3)-130 130 130 position(4)-130];
            end
            if ~isempty(app.FittingSettingPanel)
                app.FittingSettingPanel.Position = [position(3)-130 0 130 130];
            end
        end

        % Button pushed function: LinkButton
        function LinkButtonPushed(app, event)
            if strcmp(app.imastDropDown.Value,'Check')
                
                %light on
                app.LinkingLamp.Color = [0 1 0];
                
                %set file folder to today
                today = split(date,'-');
                app.SelectFolder.Value = ['D:\data\' today{3} '\' today{2} '\' today{1}];
                if ~ exist(app.SelectFolder.Value,'dir')
                    mkdir(app.SelectFolder.Value);
                end
                
                %change status
                app.imastDropDown.Enable = 'off';
                app.Browser.Enable = 'on';
                app.SelectFolder.Enable = 'on';
                app.FileName.Enable = 'on';
                app.DoneButton.Enable = 'on';
                app.AutoSave.Enable = 'on';
                app.Status.Value = 'Please Check image and data!';
                
            else
                %get selected imast
                app.imast = app.imastDropDown.Value;
                %get camima main app handle
                mainapp = app.camima;
                %put local app handle to main app
                mainapp.selectedimast.(app.imast) = app;
                %light on
                app.LinkingLamp.Color = [0 1 0];
                
                %set file folder to today
                today = split(date,'-');
                app.SelectFolder.Value = ['D:\data\' today{3} '\' today{2} '\' today{1}];
                if ~ exist(app.SelectFolder.Value,'dir')
                    mkdir(app.SelectFolder.Value);
                end
                
                %change status
                app.imastDropDown.Enable = 'off';
                app.Browser.Enable = 'on';
                app.SelectFolder.Enable = 'on';
                app.FileName.Enable = 'on';
                app.DoneButton.Enable = 'on';
                app.AutoSave.Enable = 'on';
                app.Status.Value = 'Successful Linking!';
                mainapp.Status.Value = 'one vuima successful linking in!';
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            fit(app);  
        end

        % Button pushed function: FittingSettingButton
        function FittingSettingButtonPushed(app, event)
            if ~isempty(app.Equation)&&~isempty(app.opt)
                fitset = Fitset;
                fitset.vuima = app;
                fitset.Equation.Value = app.Equation;
                fitset.Algorithm.Value = app.opt.Algorithm;
                fitset.DiffMaxChange.Value = app.opt.DiffMaxChange;
                fitset.DiffMinChange.Value = app.opt.DiffMinChange;
                
                coefficients = symvar(app.Equation{1});
                coefficients = coefficients(~strcmp(coefficients,'x'));
                coefficients = coefficients(~strcmp(coefficients,'y'));
                %create coTable
                StartPoint = num2cell(app.opt.StartPoint');
                Lower = num2cell(app.opt.Lower');
                Upper = num2cell(app.opt.Upper');
                %num2cell(true(7,1));
                fitset.CoTable.Enable = 'on';
                fitset.CoTable.Data = [coefficients StartPoint Lower Upper];
                
                fitset.MaxFunEvals.Value = app.opt.MaxFunEvals;
                fitset.MaxIter.Value = app.opt.MaxIter;
                fitset.Robust.Value = app.opt.Robust;
                fitset.TolFun.Value = app.opt.TolFun;
                fitset.TolX.Value = app.opt.TolX;
            else
                fitset = Fitset;
                fitset.vuima = app;
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %check existing file in folder with this file name...
            %get the number of file then refresh gui
            files = dir([app.SelectFolder.Value '\*' app.FileName.Value '-1.tiff']);
            if ~isempty(files)
                lastFileNameSP = split(files(end).name,'-');
                lastFileNumber = str2double(lastFileNameSP{1});
                %show on
                app.Spinner.Value = lastFileNumber;
                %set currrent
                app.curFileNum = lastFileNumber;
                %set next
                app.nextimage = lastFileNumber + 1;
            else
                %show on
                app.Spinner.Value = 0;
                %set currrent
                app.curFileNum = 0;
                %set next
                app.nextimage = 1;
            end
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            value = app.DropDown.Value;
            
            switch value
                case 'Atom'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-1.tiff'];
                case 'Light'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-2.tiff'];
                case 'Bg'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-3.tiff'];
                case 'OD'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-OD.tiff'];
            end
            
            app.curFileNum = app.Spinner.Value;
            app.curimage = imread(imaname);
            imainfo = imfinfo(imaname);
            
            if isfield(imainfo,'ImageDescription')
                Bin = split(imainfo.ImageDescription,'-');
                app.curBin.x = str2double(Bin(1));
                app.curBin.y = str2double(Bin(2));
            end
            
            %set show aspect ratio
            imgsize = size(app.curimage);
            app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
            %imshow(app.img.(cameraName).IMGOD,'Parent',vuima.ImageShow,'XData',[1 imgsize(2)],'YData',[1 imgsize(1)]);
            imagesc(app.ImageShow,app.curimage);
        end

        % Button pushed function: Browser
        function BrowserButtonPushed(app, event)
            dname = uigetdir('D:\data');
            if dname
                app.SelectFolder.Value = dname;
            end
        end

        % Button pushed function: ImJButton
        function ImJButtonPushed(app, event)
            value = app.DropDown.Value;
              
            switch value
                case 'Atom'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-1.tiff'];
                case 'Light'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-2.tiff'];
                case 'Bg'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-3.tiff'];
                case 'OD'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-OD.tiff'];
            end
            
            winopen(path);
        end

        % Callback function
        function LUTButtonPushed(app, event)
            value = app.LUTDropDown.Value;
            colormap(app.ImageShow,value);
        end

        % Value changed function: LUTDropDown
        function LUTDropDownValueChanged(app, event)
            value = app.LUTDropDown.Value;
            colormap(app.ImageShow,value);
        end

        % Close request function: VuimaMain
        function VuimaMainCloseRequest(app, event)
            
            %When delete a Vuima, we need to release imastr in Camima
            if ~isempty(app.camima)
                MainCamima = app.camima;
                if isfield(MainCamima.selectedimast,app.imast)
                    MainCamima.selectedimast = rmfield(MainCamima.selectedimast,app.imast);
                end
            end
            delete(app);
            
        end

        % Value changed function: odValue
        function odValueValueChanged(app, event)
            value = app.odValue.Value;
            if ((value > 2500) ||(value < 1000))
                app.odValue.BackgroundColor = [1.00,0.27,0.00];
            else
                app.odValue.BackgroundColor = [1.00,1.00,1.00];
            end
        end

        % Value changed function: NoofModelsSpinner
        function NoofModelsSpinnerValueChanged(app, event)
            value = app.NoofModelsSpinner.Value;
            disableallFittingModeDropDown(app);
            switch value
                case 1
                    app.FittingModeDropDown_1.Enable = 'on';
                case 2
                    app.FittingModeDropDown_1.Enable = 'on';
                    app.FittingModeDropDown_2.Enable = 'on';
                case 3
                    app.FittingModeDropDown_1.Enable = 'on';
                    app.FittingModeDropDown_2.Enable = 'on';
                    app.FittingModeDropDown_3.Enable = 'on';
                case 4
                    app.FittingModeDropDown_1.Enable = 'on';
                    app.FittingModeDropDown_2.Enable = 'on';
                    app.FittingModeDropDown_3.Enable = 'on';
                    app.FittingModeDropDown_4.Enable = 'on';
                case 5
                    app.FittingModeDropDown_1.Enable = 'on';
                    app.FittingModeDropDown_2.Enable = 'on';
                    app.FittingModeDropDown_3.Enable = 'on';
                    app.FittingModeDropDown_4.Enable = 'on';
                    app.FittingModeDropDown_5.Enable = 'on';
                case 6
                    app.FittingModeDropDown_1.Enable = 'on';
                    app.FittingModeDropDown_2.Enable = 'on';
                    app.FittingModeDropDown_3.Enable = 'on';
                    app.FittingModeDropDown_4.Enable = 'on';
                    app.FittingModeDropDown_5.Enable = 'on';
                    app.FittingModeDropDown_6.Enable = 'on';
                case 7
                    app.FittingModeDropDown_1.Enable = 'on';
                    app.FittingModeDropDown_2.Enable = 'on';
                    app.FittingModeDropDown_3.Enable = 'on';
                    app.FittingModeDropDown_4.Enable = 'on';
                    app.FittingModeDropDown_5.Enable = 'on';
                    app.FittingModeDropDown_6.Enable = 'on';
                    app.FittingModeDropDown_7.Enable = 'on';
                case 8
                    app.FittingModeDropDown_1.Enable = 'on';
                    app.FittingModeDropDown_2.Enable = 'on';
                    app.FittingModeDropDown_3.Enable = 'on';
                    app.FittingModeDropDown_4.Enable = 'on';
                    app.FittingModeDropDown_5.Enable = 'on';
                    app.FittingModeDropDown_6.Enable = 'on';
                    app.FittingModeDropDown_7.Enable = 'on';
                    app.FittingModeDropDown_8.Enable = 'on';
            end
        end

        % Button pushed function: BatchListBrowser
        function BatchListBrowserButtonPushed(app, event)
            [filename, pathname] = uigetfile('D:\data');
            if filename
                app.BatchList.Value = [pathname filename];
            end
        end

        % Button pushed function: BatchListBrowser_2
        function BatchListBrowser_2ButtonPushed(app, event)
            dname = uigetdir('D:\data');
            if dname
                app.BatchList_2.Value = dname;
            end
        end

        % Button pushed function: BatchListBrowser_3
        function BatchListBrowser_3ButtonPushed(app, event)
            dname = uigetdir('D:\data');
            if dname
                app.BatchList_3.Value = dname;
            end
        end

        % Callback function
        function DoneButton_2Pushed(app, event)
            
            
        end

        % Button pushed function: FitallButton
        function FitallButtonPushed(app, event)
            %get file list for fitting
            %we use all numerical value for importing, which including the following part:
            % fileID year month date atom series imageID
            %   56   2019  4     10   0    6      78
            %   *for atom, 0 means Rb and 1 means Na
            
            filedata = importdata(app.BatchList.Value);
            
            %split data into different field
            fileID = filedata(:,1);
            fileyear = filedata(:,2);
            filemonth = filedata(:,3);
            filedate = filedata(:,4);
            fileatom = filedata(:,5);
            imageseries = filedata(:,6);
            imageID = filedata(:,7);
               
            %set progress bar
            totalfilenum = length(fileID);
            app.Gauge.Limits = [0, totalfilenum];
            
            %Main Fitting Loop
            for i = 1:totalfilenum
                %set progress bar
                app.Gauge.Value = i;
                
                %get image name (file position)
                imaname = [app.BatchList_2.Value '\' num2str(fileyear(i),'%04d') '\' monthnum2monthchar(app,filemonth(i)) '\' num2str(filedate(i),'%02d') '\' num2str(imageID(i),'%03d') '-' atomnum2atomchar(app, fileatom(i)) num2str(imageseries(i)) '-OD.tiff'];
                
                %%readout picture and show it
                app.curimage = imread(imaname);
                imainfo = imfinfo(imaname);
                
                if isfield(imainfo,'ImageDescription')
                    Bin = split(imainfo.ImageDescription,'-');
                    app.curBin.x = str2double(Bin(1));
                    app.curBin.y = str2double(Bin(2));
                end
                
                %set show aspect ratio
                imgsize = size(app.curimage);
                app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                imagesc(app.ImageShow,app.curimage);
                
                %choose differnet model to fit
                for j = 1:app.NoofModelsSpinner.Value
                    %get fitting model name
                    %i'th fitting model
                    fittingmodelcur = app.(['FittingModeDropDown_' num2str(j)]).Value;
                    
                    %chooose model to fit
                    switch fittingmodelcur
                        case 'Gaussian'
                            fitGaussianCore(app);
                        case 'DG-Concentric'
                            fitDGConcentricCore(app);
                        case 'ParabolaGaussian'
                            fitParabolaGaussianCore(app);
                        case 'ParabolaAngle'
                            fitParabolaAngle(app);
                    end
                    
                    %%save fitting result , and set gof for comparison
                    
                    
                end
            end
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed(app, event)
            %get file list for fitting
            %we use all numerical value for importing, which including the following part:
            % fileID year month date atom series imageID
            %   56   2019  4     10   0    6      78
            %   *for atom, 0 means Rb and 1 means Na
            
            filedata = importdata(app.BatchList.Value);
            
            %split data into different field
            fileID = filedata(:,1);
            fileyear = filedata(:,2);
            filemonth = filedata(:,3);
            filedate = filedata(:,4);
            fileatom = filedata(:,5);
            imageseries = filedata(:,6);
            imageID = filedata(:,7);
            
            %set progress bar
            totalfilenum = length(fileID);
            app.Gauge.Limits = [0, totalfilenum];
            
            %Main Fitting Loop
            for i = 1:totalfilenum
                %set progress bar
                app.Gauge.Value = i;
                
                %get image name (file position)
                imaname = [app.BatchList_2.Value '\' num2str(fileyear(i),'%04d') '\' monthnum2monthchar(app,filemonth(i)) '\' num2str(filedate(i),'%02d') '\' num2str(imageID(i),'%03d') '-' atomnum2atomchar(app, fileatom(i)) num2str(imageseries(i)) '-OD.tiff'];
                
                %%readout picture and show it
                app.curimage = imread(imaname);
                
                %fitting by Gaussion to get xc and yc
                %transfer image to data with tags
                [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
                
                %set gaussian equation
                equation = {'bg + peak * exp( - 0.5* (x-xc)^2 / sigx^2 -0.5* (y-yc)^2/sigy^2)'};
                
                %set local equation to app.Equation
                app.Equation = equation;
                
                %set fitting options
                opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
                opts.Algorithm = 'Trust-Region';
                opts.DiffMaxChange = 10;
                opts.DiffMinChange = 1e-08;
                
                %set upper lower and startpoint which is very important for speeding up
                opts.Upper = [5000 1e05 Inf Inf xs ys];
                opts.Lower = [0 5 0 0 1 1];
                
                if app.AutoIniFit.Value
                    opts.StartPoint = [3000 peak 10 10 xc yc];
                else
                    opts.StartPoint = [3000 app.peak.Value app.sigy.Value app.sigx.Value app.yc.Value app.xc.Value];
                end
                
                % itterating optioins
                opts.MaxFunEvals = 300;
                opts.MaxIter = 20;
                opts.Robust = 'Off';
                opts.TolFun = 1e-06;
                opts.TolX = 1e-06;
                opts.Display = 'Notify';
                
                %set local fitting opts to app.opt
                app.opt = opts;
                
                %do fitting
                app.Status.Value = 'Start fitting... ...';
                [app.curfittingresult, app.curfittinggof] = dofit(app, XData, YData, ZData, equation, opts);
                app.Status.Value = 'Fitting done!';
                
                %save fitting result
                coValue = coeffvalues(app.curfittingresult);
                cropwidth = app.WidthEditField.Value;
                cropheight = app.HeightEditField.Value;
                
                %clip the image and show, then export
                app.curimage = imcrop(app.curimage, [(coValue(6)-cropwidth/2) (coValue(5) - cropheight/2) cropwidth cropheight]);
                
                %show picture
                imgsize = size(app.curimage);
                app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                imagesc(app.ImageShow,app.curimage);
                
                %save file
                
                imwrite(uint16(app.curimage),[app.BatchList_3.Value '\' app.FitResultFileNameEditField.Value '_' num2str(i) '.tiff']);
                
            end
        end

        % Button pushed function: CountnumberButton
        function CountnumberButtonPushed(app, event)
            %get file list for fitting
            %we use all numerical value for importing, which including the following part:
            % fileID year month date atom series imageID
            %   56   2019  4     10   0    6      78
            %   *for atom, 0 means Rb and 1 means Na
            
            filedata = importdata(app.BatchList.Value);
            
            %split data into different field
            fileID = filedata(:,1);
            fileyear = filedata(:,2);
            filemonth = filedata(:,3);
            filedate = filedata(:,4);
            fileatom = filedata(:,5);
            imageseries = filedata(:,6);
            imageID = filedata(:,7);
            
            %set progress bar
            totalfilenum = length(fileID);
            app.Gauge.Limits = [0, totalfilenum];
            
            %Main Fitting Loop
            for i = 1:totalfilenum
                %set progress bar
                app.Gauge.Value = i;
                
                %get image name (file position)
                imaname = [app.BatchList_2.Value '\' num2str(fileyear(i),'%04d') '\' monthnum2monthchar(app,filemonth(i)) '\' num2str(filedate(i),'%02d') '\' num2str(imageID(i),'%03d') '-' atomnum2atomchar(app, fileatom(i)) num2str(imageseries(i)) '-OD.tiff'];
                
                %%readout picture and show it
                app.curimage = imread(imaname);
                imainfo = imfinfo(imaname);
                
                if isfield(imainfo,'ImageDescription')
                    Bin = split(imainfo.ImageDescription,'-');
                    app.curBin.x = str2double(Bin(1));
                    app.curBin.y = str2double(Bin(2));
                end
                
                %set show aspect ratio
                imgsize = size(app.curimage);
                app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                imagesc(app.ImageShow,app.curimage);
                
                %transfer image to data with tags
                [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
                
                %set gaussian equation
                equation = {'bg + peak * exp( - 0.5* (x-xc)^2 / sigx^2 -0.5* (y-yc)^2/sigy^2)'};
                
                %set local equation to app.Equation
                app.Equation = equation;
                
                %set fitting options
                opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
                opts.Algorithm = 'Trust-Region';
                opts.DiffMaxChange = 10;
                opts.DiffMinChange = 1e-08;
                
                %set upper lower and startpoint which is very important for speeding up
                opts.Upper = [5000 1e05 Inf Inf xs ys];
                opts.Lower = [0 5 0 0 1 1];
                
                if app.AutoIniFit.Value
                    opts.StartPoint = [200 peak 10 10 xc yc];
                else
                    opts.StartPoint = [200 app.peak.Value app.sigy.Value app.sigx.Value app.yc.Value app.xc.Value];
                end
                
                % itterating optioins
                opts.MaxFunEvals = 300;
                opts.MaxIter = 30;
                opts.Robust = 'Off';
                opts.TolFun = 1e-06;
                opts.TolX = 1e-06;
                opts.Display = 'Notify';
                
                %set local fitting opts to app.opt
                app.opt = opts;
                
                %do fitting
                app.Status.Value = 'Start fitting... ...';
                [app.curfittingresult, app.curfittinggof] = dofit(app, XData, YData, ZData, equation, opts);
                app.Status.Value = 'Fitting done!';
                
                %show fitting result
                showFit(app,app.curfittingresult,app.curfittinggof,xs,ys,xc,yc);
                
                %get fitting result
                coValue = coeffvalues(app.curfittingresult);
                
                %calculating Gaussian OD for comparison
                gaussianOD = coValue(2)*coValue(4)*coValue(3)*2*pi;
                app.GaussianODEditField.Value = gaussianOD;
                
                bg = coValue(1);
                %create mask
                mask = (imgaussfilt(app.curimage,10) > (bg + 9));
                %show picture
                imgsize = size(mask);
                app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                imagesc(app.ImageShow,mask);
                
                imagemaksed = (double(app.curimage)-bg).*double(mask);
                %take summation of OD and show it up
                tmsig = 12;
                snapimage = imagemaksed(max((coValue(5)-tmsig*coValue(3)/2),1):min((coValue(5)+tmsig*coValue(3)/2),imgsize(2)),max((coValue(6)-tmsig*coValue(4)/2),1):min((coValue(6)+tmsig*coValue(4)/2),imgsize(1)));
                
                %to get proper backgorund value, we snap the around torus outside our sample to get its mean to get bg
                %imgsize = size(app.curimage);
                %snapsize = size(snapimage);
                %bg = app.bgEditField.Value;
                %bg = (sum(sum(double(app.curimage)))-sum(sum(double(snapimage))))/(imgsize(1)*imgsize(2)-snapsize(1)*snapsize(2));
                %bg = 2900.0;
                
                totalOD = sum(sum(snapimage));
                
                app.TotalODEditField.Value = totalOD;
                
                %save fitting result
                
                fitfilename = [app.BatchList_3.Value '\' app.FitResultFileNameEditField.Value '_CountOD.csv'];
                dlmwrite(fitfilename,[app.Gauge.Value, totalOD, gaussianOD],'-append');
                
            end
        end

        % Button pushed function: CountNumberButton
        function CountNumberButtonPushed(app, event)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
           
            %set gaussian equation
            equation = {'bg + peak * exp( - 0.5* (x-xc)^2 / sigx^2 -0.5* (y-yc)^2/sigy^2)'};
            
            %set local equation to app.Equation
            app.Equation = equation;
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [5000 1e05 Inf Inf xs ys];
            opts.Lower = [0 5 0 0 1 1];
            
            if app.AutoIniFit.Value
                opts.StartPoint = [200 peak 10 10 xc yc];
            else
                opts.StartPoint = [200 app.peak.Value app.sigy.Value app.sigx.Value app.yc.Value app.xc.Value];
            end
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 30;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %set local fitting opts to app.opt
            app.opt = opts;
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [app.curfittingresult, app.curfittinggof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,app.curfittingresult,app.curfittinggof,xs,ys,xc,yc);
            
            %get fitting result
            coValue = coeffvalues(app.curfittingresult);
            
            %calculating Gaussian OD for comparison
            gaussianOD = coValue(2)*coValue(4)*coValue(3)*2*pi;
            app.GaussianODEditField.Value = gaussianOD;
            
            
            bg = coValue(1);
            %{
            % Following method by taking certain size as window to do counting
            r = 2.5;
            xhr = coValue(4)*r; yhr = coValue(3)*r;
            totalOD = sum(sum(double(app.curimage(coValue(5)-yhr:coValue(5)+yhr,coValue(6)-xhr:coValue(6)+xhr))-bg));
            %}
            % Following method by using mask as filter
            
            %create mask
            mask = (imgaussfilt(app.curimage,10) > (bg + 9));
            %show picture
            imgsize = size(mask);
            app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
            imagesc(app.ImageShow,mask);
            
            imagemaksed = (double(app.curimage)-bg).*double(mask);
            %take summation of OD and show it up
            tmsig = app.tmsigEditField.Value;
            snapimage = imagemaksed((coValue(5)-tmsig*coValue(3)/2):(coValue(5)+tmsig*coValue(3)/2),(coValue(6)-tmsig*coValue(4)/2):(coValue(6)+tmsig*coValue(4)/2));
            
            %to get proper backgorund value, we snap the around torus outside our sample to get its mean to get bg
            %imgsize = size(app.curimage);
            %snapsize = size(snapimage);
            %bg = app.bgEditField.Value;
            %bg = (sum(sum(double(app.curimage)))-sum(sum(double(snapimage))))/(imgsize(1)*imgsize(2)-snapsize(1)*snapsize(2));
            %bg = 2900.0;
            
            totalOD = sum(sum(snapimage));


            app.TotalODEditField.Value = totalOD;
        end

        % Button pushed function: ReODButton
        function ReODButtonPushed(app, event)
            % get file list for re-calculate OD
            % we use all numerical value for importing, which including the following part:
            % fileID | year | month | date | atom | series | imageID | Condi | Csat | Bin | Expo
            %   56   | 2019 |   4   |  10  |  0   |   6    |  78     |   1   |  39  |  1  |   5
            %   *for atom, 0 means Rb and 1 means Na
            
            filedata = importdata(app.BatchList.Value);
            
            %split data into different field
            fileID = filedata(:,1);
            fileyear = filedata(:,2);
            filemonth = filedata(:,3);
            filedate = filedata(:,4);
            fileatom = filedata(:,5);
            imageseries = filedata(:,6);
            imageID = filedata(:,7);
            imaCondi = filedata(:,8);
            imaCsat = filedata(:,9);
            imaBin = filedata(:,10);
            imaExpo = filedata(:,11);
            
            %set progress bar
            totalfilenum = length(fileID);
            app.Gauge.Limits = [0, totalfilenum];
            
            %Main calculate OD Loop
            for i = 1:totalfilenum
                %set progress bar
                app.Gauge.Value = i;
                
                %get image name (file position)
                imapath1 = [app.BatchList_2.Value '\' num2str(fileyear(i),'%04d') '\' monthnum2monthchar(app,filemonth(i)) '\' num2str(filedate(i),'%02d') '\' num2str(imageID(i),'%03d') '-' atomnum2atomchar(app, fileatom(i)) num2str(imageseries(i)) '-1.tiff'];
                imapath2 = [app.BatchList_2.Value '\' num2str(fileyear(i),'%04d') '\' monthnum2monthchar(app,filemonth(i)) '\' num2str(filedate(i),'%02d') '\' num2str(imageID(i),'%03d') '-' atomnum2atomchar(app, fileatom(i)) num2str(imageseries(i)) '-2.tiff'];
                imapath3 = [app.BatchList_2.Value '\' num2str(fileyear(i),'%04d') '\' monthnum2monthchar(app,filemonth(i)) '\' num2str(filedate(i),'%02d') '\' num2str(imageID(i),'%03d') '-' atomnum2atomchar(app, fileatom(i)) num2str(imageseries(i)) '-3.tiff'];
                imapathOD = [app.BatchList_2.Value '\' num2str(fileyear(i),'%04d') '\' monthnum2monthchar(app,filemonth(i)) '\' num2str(filedate(i),'%02d') '\' num2str(imageID(i),'%03d') '-' atomnum2atomchar(app, fileatom(i)) num2str(imageseries(i)) '-OD.tiff'];
                
                %%readout picture and show it
                ima1 = imread(imapath1);
                ima2 = imread(imapath2);
                ima3 = imread(imapath3);
                
                %% push old OD file to app.curimage
                %% get old OD file's image infromation about binning
                imainfo = imfinfo(imapathOD);
                
                %get binning information and set it to app's binning
                if isfield(imainfo,'ImageDescription')
                    Bin = split(imainfo.ImageDescription,'-');
                    app.curBin.x = str2double(Bin(1));
                    app.curBin.y = str2double(Bin(2));
                end
                
                
                %get OD         
                %if Condi is 1, add modification, if Condi is 0, no modification
                app.curimage =...
                    3000 + 1000*(CutLog(app,(double(ima2) - double(ima3))./...
                    (double(ima1) - double(ima3))) + ...
                    imaCondi(i) * (double(ima2) - double(ima1))/(imaExpo(i)*(imaBin(i)^2)*imaCsat(i)));
                
                %save image %%%%%%%%%%%%%%%%%%% not finished yet %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 2019.07.15  call save function ????
                %try to write a single image process to test its performance
                %continue in 07.16
                imwrite(uint16(app.curimage),[app.BatchList_2.Value '\' num2str(fileyear(i),'%04d') '\' monthnum2monthchar(app,filemonth(i)) '\' num2str(filedate(i),'%02d') '\' num2str(imageID(i),'%03d') '-' atomnum2atomchar(app, fileatom(i)) num2str(imageseries(i)) '-OD.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
                app.curimage = imread([app.BatchList_2.Value '\' num2str(fileyear(i),'%04d') '\' monthnum2monthchar(app,filemonth(i)) '\' num2str(filedate(i),'%02d') '\' num2str(imageID(i),'%03d') '-' atomnum2atomchar(app, fileatom(i)) num2str(imageseries(i)) '-OD.tiff']);
                
                %set show aspect ratio
                imgsize = size(app.curimage);
                app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
                imagesc(app.ImageShow,app.curimage);
                
            end
        end

        % Button pushed function: ReODButton_2
        function ReODButton_2Pushed(app, event)
            %%%%%%% this function is for re-calculating OD for single image which shows in the current panel  %%%%%%
            %%%% get path of all images from current file name
            imapath1 = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-1.tiff'];
            imapath2 = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-2.tiff'];
            imapath3 = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-3.tiff'];
            imapathOD = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-OD.tiff'];
            
            %%readout picture and show it
            ima1 = imread(imapath1);
            ima2 = imread(imapath2);
            ima3 = imread(imapath3);
            imaOD = imread(imapathOD);
            
            %% push old OD file to app.curimage
            app.curimage = imaOD;
            %% get old OD file's image infromation about binning 
            imainfo = imfinfo(imapathOD);
            
            %get binning information and set it to app's binning
            if isfield(imainfo,'ImageDescription')
                Bin = split(imainfo.ImageDescription,'-');
                app.curBin.x = str2double(Bin(1));
                app.curBin.y = str2double(Bin(2));
            end
            
            %get OD
            %if Condi is 1, add modification, if Condi is 0, no modification
            app.curimage =...
                3000 + 1000*(CutLog(app,(double(ima2) - double(ima3))./...
                (double(ima1) - double(ima3))) + ...
                app.Condi.Value * (double(ima2) - double(ima1))/(app.Expo.Value*((app.Bin.Value)^2)*app.Csat.Value));
            
            %save image %%%%%%%%%%%%%%%%%%% not finished yet %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 2019.07.15  call save function ????
            %try to write a single image process to test its performance
            %continue in 07.16
            imwrite(uint16(app.curimage),[app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-OD1.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            
            app.curimage = imread([app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-OD1.tiff']);
            
            %set show aspect ratio
            imgsize = size(app.curimage);
            app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
            imagesc(app.ImageShow,app.curimage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VuimaMain = uifigure('Visible', 'off');
            app.VuimaMain.Name = 'Vuima';
            app.VuimaMain.CloseRequestFcn = createCallbackFcn(app, @VuimaMainCloseRequest, true);
            app.Toolbar = uitoolbar(app.VuimaMain);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool4 = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.VuimaMain);
            app.FittingSettingPanel = uipanel(app.GridLayout);
            app.Auto = uicheckbox(app.FittingSettingPanel);
            app.Auto.Text = 'Auto';
            app.FitButton = uibutton(app.FittingSettingPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.FittingModeDropDown = uidropdown(app.FittingSettingPanel);
            app.FittingModeDropDown.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'Customed'};
            app.FittingModeDropDown.Editable = 'on';
            app.FittingModeDropDown.Value = 'Gaussian';
            app.FittingSettingButton = uibutton(app.FittingSettingPanel, 'push');
            app.FittingSettingButton.ButtonPushedFcn = createCallbackFcn(app, @FittingSettingButtonPushed, true);
            app.FittingSettingButton.Text = 'Setting';
            app.StopButton = uibutton(app.FittingSettingPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.pksSpinnerLabel = uilabel(app.FittingSettingPanel);
            app.pksSpinnerLabel.Text = 'pks';
            app.pks = uispinner(app.FittingSettingPanel);
            app.pks.Limits = [1 Inf];
            app.pks.Value = 1;
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Status = uieditfield(app.GridLayout2, 'text');
            app.odValue = uieditfield(app.GridLayout2, 'numeric');
            app.odValue.ValueChangedFcn = createCallbackFcn(app, @odValueValueChanged, true);
            app.odValue.Editable = 'off';
            app.CountRate = uieditfield(app.GridLayout2, 'numeric');
            app.CountRate.Editable = 'off';
            app.Light = uieditfield(app.GridLayout2, 'numeric');
            app.Light.Editable = 'off';
            app.QuickFitting = uipanel(app.GridLayout);
            app.QuickFitting.Title = 'Quick fit setting';
            app.peakEditFieldLabel = uilabel(app.QuickFitting);
            app.peakEditFieldLabel.Text = 'peak';
            app.peak = uieditfield(app.QuickFitting, 'numeric');
            app.xcEditFieldLabel = uilabel(app.QuickFitting);
            app.xcEditFieldLabel.Text = 'xc';
            app.xc = uieditfield(app.QuickFitting, 'numeric');
            app.ycEditFieldLabel = uilabel(app.QuickFitting);
            app.ycEditFieldLabel.Text = 'yc';
            app.yc = uieditfield(app.QuickFitting, 'numeric');
            app.sigxEditFieldLabel = uilabel(app.QuickFitting);
            app.sigxEditFieldLabel.Text = 'sigx';
            app.sigx = uieditfield(app.QuickFitting, 'numeric');
            app.sigyEditFieldLabel = uilabel(app.QuickFitting);
            app.sigyEditFieldLabel.Text = 'sigy';
            app.sigy = uieditfield(app.QuickFitting, 'numeric');
            app.peak_2 = uieditfield(app.QuickFitting, 'numeric');
            app.xc_2 = uieditfield(app.QuickFitting, 'numeric');
            app.yc_2 = uieditfield(app.QuickFitting, 'numeric');
            app.sigx_2 = uieditfield(app.QuickFitting, 'numeric');
            app.sigy_2 = uieditfield(app.QuickFitting, 'numeric');
            app.AutoIniFit = uicheckbox(app.QuickFitting);
            app.AutoIniFit.Text = 'Auto';
            app.ErrorData = uitable(app.GridLayout);
            app.ErrorData.ColumnName = {'Error Parameter'; 'Value'};
            app.ErrorData.RowName = {};
            app.ErrorData.ColumnEditable = true;
            app.FittingData = uitable(app.GridLayout);
            app.FittingData.ColumnName = {'Parameter'; 'Value'};
            app.FittingData.RowName = {};
            app.FittingData.ColumnEditable = true;
            app.MainTabGroup = uitabgroup(app.GridLayout);
            app.HomeTab = uitab(app.MainTabGroup);
            app.HomeTab.Title = 'Home';
            app.GridLayout3 = uigridlayout(app.HomeTab);
            app.LinkingPanel = uipanel(app.GridLayout3);
            app.LinkingPanel.Title = 'Linking';
            app.LinkingLamp = uilamp(app.LinkingPanel);
            app.LinkButton = uibutton(app.LinkingPanel, 'push');
            app.LinkButton.ButtonPushedFcn = createCallbackFcn(app, @LinkButtonPushed, true);
            app.LinkButton.Text = 'Link';
            app.imastDropDownLabel = uilabel(app.LinkingPanel);
            app.imastDropDownLabel.Enable = 'off';
            app.imastDropDownLabel.Text = 'imast';
            app.imastDropDown = uidropdown(app.LinkingPanel);
            app.imastDropDown.Items = {'Check'};
            app.imastDropDown.Value = 'Check';
            app.CheckImagePanel = uipanel(app.GridLayout3);
            app.CheckImagePanel.Title = 'Check Image';
            app.DropDown = uidropdown(app.CheckImagePanel);
            app.DropDown.Items = {'Atom', 'Light', 'Bg', 'OD'};
            app.DropDown.Value = 'Atom';
            app.CheckButton = uibutton(app.CheckImagePanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Spinner = uispinner(app.CheckImagePanel);
            app.ImJButton = uibutton(app.CheckImagePanel, 'push');
            app.ImJButton.ButtonPushedFcn = createCallbackFcn(app, @ImJButtonPushed, true);
            app.ImJButton.Text = 'ImJ';
            app.LUTDropDown = uidropdown(app.CheckImagePanel);
            app.LUTDropDown.Items = {'jet', 'gray', 'parula', 'hsv', 'hot', 'cool', 'spring'};
            app.LUTDropDown.Editable = 'on';
            app.LUTDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTDropDownValueChanged, true);
            app.LUTDropDown.Value = 'jet';
            app.SavingPanel = uipanel(app.GridLayout3);
            app.SavingPanel.Title = 'Saving';
            app.Browser = uibutton(app.SavingPanel, 'push');
            app.Browser.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.Browser.Enable = 'off';
            app.Browser.Text = 'Browser';
            app.DoneButton = uibutton(app.SavingPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.AutoSave = uicheckbox(app.SavingPanel);
            app.AutoSave.Enable = 'off';
            app.AutoSave.Text = {'Auto'; 'Save'};
            app.SelectFolderEditFieldPGLabel = uilabel(app.SavingPanel);
            app.SelectFolderEditFieldPGLabel.Text = 'Select  Folder';
            app.SelectFolder = uieditfield(app.SavingPanel, 'text');
            app.SelectFolder.Enable = 'off';
            app.FileNameEditFieldPGLabel = uilabel(app.SavingPanel);
            app.FileNameEditFieldPGLabel.Text = 'File Name';
            app.FileName = uieditfield(app.SavingPanel, 'text');
            app.FileName.Enable = 'off';
            app.ViewTab = uitab(app.MainTabGroup);
            app.ViewTab.Title = 'View';
            app.HighIntensityImageModify = uipanel(app.ViewTab);
            app.HighIntensityImageModify.Title = 'High Intensity Image';
            app.expoEditFieldLabel = uilabel(app.HighIntensityImageModify);
            app.expoEditFieldLabel.Text = 'expo';
            app.Expo = uieditfield(app.HighIntensityImageModify, 'numeric');
            app.Expo.Limits = [1 Inf];
            app.Expo.Value = 1;
            app.BinEditFieldLabel = uilabel(app.HighIntensityImageModify);
            app.BinEditFieldLabel.Text = 'Bin';
            app.Bin = uieditfield(app.HighIntensityImageModify, 'numeric');
            app.Bin.Limits = [1 2];
            app.Bin.Value = 2;
            app.ModifyEditFieldLabel = uilabel(app.HighIntensityImageModify);
            app.ModifyEditFieldLabel.Text = 'Modify?';
            app.Condi = uieditfield(app.HighIntensityImageModify, 'numeric');
            app.Condi.Limits = [0 1];
            app.CsatEditFieldLabel = uilabel(app.HighIntensityImageModify);
            app.CsatEditFieldLabel.Text = 'Csat';
            app.Csat = uieditfield(app.HighIntensityImageModify, 'numeric');
            app.Csat.Limits = [0 Inf];
            app.Csat.Value = 892;
            app.ProbeLightModify = uipanel(app.ViewTab);
            app.ProbeLightModify.Title = 'Probe Light Modify';
            app.LxEditFieldLabel = uilabel(app.ProbeLightModify);
            app.LxEditFieldLabel.Text = 'Lx';
            app.Lx = uieditfield(app.ProbeLightModify, 'numeric');
            app.Lx.Limits = [0 Inf];
            app.WEditFieldLabel = uilabel(app.ProbeLightModify);
            app.WEditFieldLabel.Text = 'W';
            app.W = uieditfield(app.ProbeLightModify, 'numeric');
            app.W.Limits = [0 Inf];
            app.UyEditFieldLabel = uilabel(app.ProbeLightModify);
            app.UyEditFieldLabel.Text = 'Uy';
            app.Uy = uieditfield(app.ProbeLightModify, 'numeric');
            app.Uy.Limits = [0 Inf];
            app.HEditFieldLabel = uilabel(app.ProbeLightModify);
            app.HEditFieldLabel.Text = 'H';
            app.H = uieditfield(app.ProbeLightModify, 'numeric');
            app.H.Limits = [0 Inf];
            app.LModifyEditFieldLabel = uilabel(app.ProbeLightModify);
            app.LModifyEditFieldLabel.Text = 'L Modify?';
            app.LModify = uieditfield(app.ProbeLightModify, 'numeric');
            app.CountNumberPanel = uipanel(app.ViewTab);
            app.CountNumberPanel.Title = 'Count number';
            app.CountNumberButton = uibutton(app.CountNumberPanel, 'push');
            app.CountNumberButton.ButtonPushedFcn = createCallbackFcn(app, @CountNumberButtonPushed, true);
            app.CountNumberButton.Text = 'Count';
            app.TotalODEditFieldLabel = uilabel(app.CountNumberPanel);
            app.TotalODEditFieldLabel.Text = 'Total OD';
            app.TotalODEditField = uieditfield(app.CountNumberPanel, 'numeric');
            app.TotalODEditField.Editable = 'off';
            app.NumberEditFieldLabel = uilabel(app.CountNumberPanel);
            app.NumberEditFieldLabel.Text = 'Number';
            app.NumberEditField = uieditfield(app.CountNumberPanel, 'numeric');
            app.NumberEditField.Editable = 'off';
            app.AutoCountButtun = uicheckbox(app.CountNumberPanel);
            app.AutoCountButtun.Text = 'Auto';
            app.GaussianODEditFieldLabel = uilabel(app.CountNumberPanel);
            app.GaussianODEditFieldLabel.Text = 'Gaussian OD';
            app.GaussianODEditField = uieditfield(app.CountNumberPanel, 'numeric');
            app.GaussianODEditField.Editable = 'off';
            app.tmsigEditFieldLabel = uilabel(app.CountNumberPanel);
            app.tmsigEditFieldLabel.Text = 'tmsig';
            app.tmsigEditField = uieditfield(app.CountNumberPanel, 'numeric');
            app.ReODPanel = uipanel(app.ViewTab);
            app.ReODPanel.Title = 'ReOD';
            app.ReODButton_2 = uibutton(app.ReODPanel, 'push');
            app.ReODButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReODButton_2Pushed, true);
            app.ReODButton_2.Text = 'ReOD';
            app.BatchTab = uitab(app.MainTabGroup);
            app.BatchTab.Title = 'Batch';
            app.BatchListBrowser = uibutton(app.BatchTab, 'push');
            app.BatchListBrowser.ButtonPushedFcn = createCallbackFcn(app, @BatchListBrowserButtonPushed, true);
            app.BatchListBrowser.Text = 'Browser';
            app.SelectFileListLabel = uilabel(app.BatchTab);
            app.SelectFileListLabel.Text = 'Select  File List';
            app.BatchList = uieditfield(app.BatchTab, 'text');
            app.FittingModeDropDown_1 = uidropdown(app.BatchTab);
            app.FittingModeDropDown_1.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'ParabolaAngle', 'Customed'};
            app.FittingModeDropDown_1.Editable = 'on';
            app.FittingModeDropDown_1.Value = 'Gaussian';
            app.FittingModeDropDown_2 = uidropdown(app.BatchTab);
            app.FittingModeDropDown_2.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'ParabolaAngle', 'Customed'};
            app.FittingModeDropDown_2.Editable = 'on';
            app.FittingModeDropDown_2.Enable = 'off';
            app.FittingModeDropDown_2.Value = 'Gaussian';
            app.FittingModeDropDown_3 = uidropdown(app.BatchTab);
            app.FittingModeDropDown_3.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'ParabolaAngle', 'Customed'};
            app.FittingModeDropDown_3.Editable = 'on';
            app.FittingModeDropDown_3.Enable = 'off';
            app.FittingModeDropDown_3.Value = 'Gaussian';
            app.Gauge = uigauge(app.BatchTab, 'linear');
            app.BatchListBrowser_2 = uibutton(app.BatchTab, 'push');
            app.BatchListBrowser_2.ButtonPushedFcn = createCallbackFcn(app, @BatchListBrowser_2ButtonPushed, true);
            app.BatchListBrowser_2.Text = 'Browser';
            app.ImageFolderLabel = uilabel(app.BatchTab);
            app.ImageFolderLabel.Text = 'Image Folder';
            app.BatchList_2 = uieditfield(app.BatchTab, 'text');
            app.BatchListBrowser_3 = uibutton(app.BatchTab, 'push');
            app.BatchListBrowser_3.ButtonPushedFcn = createCallbackFcn(app, @BatchListBrowser_3ButtonPushed, true);
            app.BatchListBrowser_3.Text = 'Browser';
            app.OutputFolderLabel = uilabel(app.BatchTab);
            app.OutputFolderLabel.Text = 'Output Folder';
            app.BatchList_3 = uieditfield(app.BatchTab, 'text');
            app.FittingModeDropDown_4 = uidropdown(app.BatchTab);
            app.FittingModeDropDown_4.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'ParabolaAngle', 'Customed'};
            app.FittingModeDropDown_4.Editable = 'on';
            app.FittingModeDropDown_4.Enable = 'off';
            app.FittingModeDropDown_4.Value = 'Gaussian';
            app.FittingModeDropDown_5 = uidropdown(app.BatchTab);
            app.FittingModeDropDown_5.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'ParabolaAngle', 'Customed'};
            app.FittingModeDropDown_5.Editable = 'on';
            app.FittingModeDropDown_5.Enable = 'off';
            app.FittingModeDropDown_5.Value = 'Gaussian';
            app.FittingModeDropDown_6 = uidropdown(app.BatchTab);
            app.FittingModeDropDown_6.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'ParabolaAngle', 'Customed'};
            app.FittingModeDropDown_6.Editable = 'on';
            app.FittingModeDropDown_6.Enable = 'off';
            app.FittingModeDropDown_6.Value = 'Gaussian';
            app.FittingModeDropDown_7 = uidropdown(app.BatchTab);
            app.FittingModeDropDown_7.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'ParabolaAngle', 'Customed'};
            app.FittingModeDropDown_7.Editable = 'on';
            app.FittingModeDropDown_7.Enable = 'off';
            app.FittingModeDropDown_7.Value = 'Gaussian';
            app.FittingModeDropDown_8 = uidropdown(app.BatchTab);
            app.FittingModeDropDown_8.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'ParabolaAngle', 'Customed'};
            app.FittingModeDropDown_8.Editable = 'on';
            app.FittingModeDropDown_8.Enable = 'off';
            app.FittingModeDropDown_8.Value = 'Gaussian';
            app.NoofModelsSpinnerLabel = uilabel(app.BatchTab);
            app.NoofModelsSpinnerLabel.Text = 'No. of Models';
            app.NoofModelsSpinner = uispinner(app.BatchTab);
            app.NoofModelsSpinner.Limits = [1 8];
            app.NoofModelsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofModelsSpinnerValueChanged, true);
            app.NoofModelsSpinner.Value = 1;
            app.ProcessingLampLabel = uilabel(app.BatchTab);
            app.ProcessingLampLabel.Text = 'Processing?';
            app.ProcessingLamp = uilamp(app.BatchTab);
            app.StopButton_2 = uibutton(app.BatchTab, 'push');
            app.StopButton_2.Text = 'Stop';
            app.FitallButton = uibutton(app.BatchTab, 'push');
            app.FitallButton.ButtonPushedFcn = createCallbackFcn(app, @FitallButtonPushed, true);
            app.FitallButton.Text = 'Fit all';
            app.PauseButton_2 = uibutton(app.BatchTab, 'state');
            app.PauseButton_2.Text = 'Pause';
            app.FitResultFileNameEditFieldLabel = uilabel(app.BatchTab);
            app.FitResultFileNameEditFieldLabel.Text = 'Fit Result File Name';
            app.FitResultFileNameEditField = uieditfield(app.BatchTab, 'text');
            app.ExportImageButton = uibutton(app.BatchTab, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed, true);
            app.ExportImageButton.Text = 'Export Image';
            app.WidthEditFieldLabel = uilabel(app.BatchTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.BatchTab, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.BatchTab);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.BatchTab, 'numeric');
            app.CountnumberButton = uibutton(app.BatchTab, 'push');
            app.CountnumberButton.ButtonPushedFcn = createCallbackFcn(app, @CountnumberButtonPushed, true);
            app.CountnumberButton.Text = {'Count '; 'number'};
            app.ReODButton = uibutton(app.BatchTab, 'push');
            app.ReODButton.ButtonPushedFcn = createCallbackFcn(app, @ReODButtonPushed, true);
            app.ReODButton.Text = 'ReOD';
            app.fittingX = uiaxes(app.GridLayout);
            app.fittingY = uiaxes(app.GridLayout);
            app.ImageShow = uiaxes(app.GridLayout);
            % Show the figure after all components are created
            app.VuimaMain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vuima

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VuimaMain)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VuimaMain)
        end
    end
end